classdef CARODOTManip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CARODOTManipUIFigure           matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewfileMenu                    matlab.ui.container.Menu
        LoadfileMenu                   matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        RestartMenu                    matlab.ui.container.Menu
        appandallconnectionsMenu       matlab.ui.container.Menu
        appandkeepspectrographMenu     matlab.ui.container.Menu
        restartappMenu                 matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        PathsMenu                      matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ExportapptoworkspaceMenu       matlab.ui.container.Menu
        ElliptecMenu                   matlab.ui.container.Menu
        DeviceMenu                     matlab.ui.container.Menu
        RotationstageMenu              matlab.ui.container.Menu
        RotationmountMenu              matlab.ui.container.Menu
        LinearMenu                     matlab.ui.container.Menu
        posSliderMenu                  matlab.ui.container.Menu
        ProgrammingMenu                matlab.ui.container.Menu
        AndorMenu                      matlab.ui.container.Menu
        SpectrometerMenu               matlab.ui.container.Menu
        CCDMenu                        matlab.ui.container.Menu
        ProgrammingspectrometerMenu    matlab.ui.container.Menu
        ProgrammingCCDMenu             matlab.ui.container.Menu
        BSIMenu                        matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        AppStateToggleTool             matlab.ui.container.toolbar.ToggleTool
        SaveTool                       matlab.ui.container.toolbar.PushTool
        AutosaveToggleTool             matlab.ui.container.toolbar.ToggleTool
        PauseTool                      matlab.ui.container.toolbar.ToggleTool
        StopTool                       matlab.ui.container.toolbar.ToggleTool
        AcquirePicturePushTool         matlab.ui.container.toolbar.PushTool
        AcquireCustMoviePushTool       matlab.ui.container.toolbar.PushTool
        NewMoviePushTool               matlab.ui.container.toolbar.PushTool
        shutter_tool                   matlab.ui.container.toolbar.PushTool
        FastAcquire                    matlab.ui.container.toolbar.PushTool
        ContinuousAcqToggleTool        matlab.ui.container.toolbar.ToggleTool
        ContinuousAcqPushTool          matlab.ui.container.toolbar.PushTool
        AppGridLayout                  matlab.ui.container.GridLayout
        addcomButton                   matlab.ui.control.Button
        MeasurementConfigTab           matlab.ui.container.TabGroup
        ParamsTab                      matlab.ui.container.Tab
        PWnexttoBSCheckBox             matlab.ui.control.CheckBox
        StatusLamp                     matlab.ui.control.Lamp
        LampLabel                      matlab.ui.control.Label
        TKEditField                    matlab.ui.control.NumericEditField
        TKEditFieldLabel               matlab.ui.control.Label
        ExcitationTab                  matlab.ui.container.Tab
        Switch                         matlab.ui.control.Switch
        ExcPow                         matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        GetPower                       matlab.ui.control.Button
        EWLnmEditField                 matlab.ui.control.NumericEditField
        EWLnmEditFieldLabel            matlab.ui.control.Label
        SourceDropDown                 matlab.ui.control.DropDown
        SourceDropDownLabel            matlab.ui.control.Label
        DetectionTab                   matlab.ui.container.Tab
        freezeCheckBox                 matlab.ui.control.CheckBox
        backgroundCheckBox             matlab.ui.control.CheckBox
        sLabel                         matlab.ui.control.Label
        tsubacqsubLabel                matlab.ui.control.Label
        AcqTimeEditField               matlab.ui.control.NumericEditField
        DetectorDropDown               matlab.ui.control.DropDown
        DetectorDropDownLabel          matlab.ui.control.Label
        MeasurementControlsTab         matlab.ui.container.TabGroup
        DMapTab                        matlab.ui.container.Tab
        InfoButton                     matlab.ui.control.Button
        stepSpinner                    matlab.ui.control.Spinner
        stepSpinnerLabel               matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        ysizeSpinner                   matlab.ui.control.Spinner
        ysizeSpinnerLabel              matlab.ui.control.Label
        xsizeSpinner                   matlab.ui.control.Spinner
        xsizeSpinnerLabel              matlab.ui.control.Label
        y_0Spinner                     matlab.ui.control.Spinner
        y_0SpinnerLabel                matlab.ui.control.Label
        x_0Spinner                     matlab.ui.control.Spinner
        x_0SpinnerLabel                matlab.ui.control.Label
        IfxTab                         matlab.ui.container.Tab
        instrunitEditField             matlab.ui.control.EditField
        inEditFieldLabel               matlab.ui.control.Label
        MasterRunButton                matlab.ui.control.Button
        stepEditField                  matlab.ui.control.NumericEditField
        stepEditFieldLabel             matlab.ui.control.Label
        endEditField                   matlab.ui.control.NumericEditField
        endEditFieldLabel              matlab.ui.control.Label
        startEditField                 matlab.ui.control.NumericEditField
        startEditFieldLabel            matlab.ui.control.Label
        instrDropDown                  matlab.ui.control.DropDown
        instrDropDownLabel             matlab.ui.control.Label
        InstrumentControlsTab          matlab.ui.container.TabGroup
        SpectroTab                     matlab.ui.container.Tab
        CCDPanel                       matlab.ui.container.Panel
        AcquireButton                  matlab.ui.control.Button
        CyctimeSpinner                 matlab.ui.control.Spinner
        CyctimeSpinnerLabel            matlab.ui.control.Label
        AccumSpinner                   matlab.ui.control.Spinner
        AccumSpinnerLabel              matlab.ui.control.Label
        AndorReadoutModeDropDown       matlab.ui.control.DropDown
        ReadoutmodeLabel_2             matlab.ui.control.Label
        GainDropDown                   matlab.ui.control.DropDown
        GainDropDownLabel              matlab.ui.control.Label
        CCDTLamp                       matlab.ui.control.Lamp
        CCDTLampLabel                  matlab.ui.control.Label
        AndorAcqModeDropDown           matlab.ui.control.DropDown
        ReadoutmodeLabel               matlab.ui.control.Label
        MonochromatorPanel             matlab.ui.container.Panel
        GratingDropDown                matlab.ui.control.DropDown
        GratingDropDownLabel           matlab.ui.control.Label
        OutputportDropDown             matlab.ui.control.DropDown
        OutputportDropDownLabel        matlab.ui.control.Label
        InputportDropDown              matlab.ui.control.DropDown
        InputportDropDownLabel         matlab.ui.control.Label
        WavelengthnmEditField          matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        FSMTab                         matlab.ui.container.Tab
        FSMStepEditField               matlab.ui.control.NumericEditField
        FSMStepEditFieldLabel          matlab.ui.control.Label
        HButton                        matlab.ui.control.Button
        LButton                        matlab.ui.control.Button
        RButton                        matlab.ui.control.Button
        DButton                        matlab.ui.control.Button
        UButton                        matlab.ui.control.Button
        TargetLabel                    matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        ReadoutLabel                   matlab.ui.control.Label
        FSM_yTEditField                matlab.ui.control.NumericEditField
        FSM_xTEditField                matlab.ui.control.NumericEditField
        FSM_yREditField                matlab.ui.control.NumericEditField
        FSM_xREditField                matlab.ui.control.NumericEditField
        RotationTab                    matlab.ui.container.Tab
        DetPolPanel                    matlab.ui.container.Panel
        PolarDetReadoutEditField       matlab.ui.control.NumericEditField
        ReadoutEditField_4Label_2      matlab.ui.control.Label
        PolarDetTargetEditField        matlab.ui.control.NumericEditField
        TargetEditField_4Label_2       matlab.ui.control.Label
        PolarDetJogStepEditField       matlab.ui.control.NumericEditField
        StepEditFieldLabel_2           matlab.ui.control.Label
        PolarDetJogFw                  matlab.ui.control.Button
        PolarDetJogBk                  matlab.ui.control.Button
        ExcPolPanel                    matlab.ui.container.Panel
        PolarExcTargetEditField        matlab.ui.control.NumericEditField
        TargetEditField_4Label         matlab.ui.control.Label
        PolarExcJogStepEditField       matlab.ui.control.NumericEditField
        StepEditFieldLabel             matlab.ui.control.Label
        PolarExcReadoutEditField       matlab.ui.control.NumericEditField
        ReadoutEditField_4Label        matlab.ui.control.Label
        PolarExcJogBk                  matlab.ui.control.Button
        PolarExcJogFw                  matlab.ui.control.Button
        PolarSelectedInstrument        matlab.ui.control.DropDown
        SelectedinstrLabel             matlab.ui.control.Label
        FiltersTab                     matlab.ui.container.Tab
        FiltersetBPanel                matlab.ui.container.Panel
        FiltersetAPanel                matlab.ui.container.Panel
        StepEditField_3                matlab.ui.control.NumericEditField
        StepLabel                      matlab.ui.control.Label
        ReadoutEditField_3             matlab.ui.control.NumericEditField
        ReadoutLabel_4                 matlab.ui.control.Label
        StepEditField_2                matlab.ui.control.NumericEditField
        StepLabel_3                    matlab.ui.control.Label
        ReadoutEditField_2             matlab.ui.control.NumericEditField
        ReadoutLabel_3                 matlab.ui.control.Label
        TargetLabel_2                  matlab.ui.control.Label
        TargetEditField_3              matlab.ui.control.NumericEditField
        TargetEditField_2              matlab.ui.control.NumericEditField
        TargetLabel_3                  matlab.ui.control.Label
        StepEditField                  matlab.ui.control.NumericEditField
        StepLabel_2                    matlab.ui.control.Label
        ReadoutEditField               matlab.ui.control.NumericEditField
        ReadoutLabel_2                 matlab.ui.control.Label
        TargetEditField                matlab.ui.control.NumericEditField
        AngleTargetLabel               matlab.ui.control.Label
        BwdButton                      matlab.ui.control.Button
        FwdButton                      matlab.ui.control.Button
        FwdButton_3                    matlab.ui.control.Button
        FwdButton_2                    matlab.ui.control.Button
        BwdButton_2                    matlab.ui.control.Button
        BwdButton_3                    matlab.ui.control.Button
        CustfilterTab                  matlab.ui.container.Tab
        FiltersetDropDown              matlab.ui.control.DropDown
        FiltersetDropDownLabel         matlab.ui.control.Label
        n_texteffEditField             matlab.ui.control.NumericEditField
        n_texteffLabel                 matlab.ui.control.Label
        lambda0EditField               matlab.ui.control.NumericEditField
        lambda0Label                   matlab.ui.control.Label
        lambdathetalambda0sqrt1leftfracsin2thetan_eff2rightLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        TranslationTab                 matlab.ui.container.Tab
        ShutterSwitch                  matlab.ui.control.Switch
        ShutterSwitchLabel             matlab.ui.control.Label
        BSslider1Switch                matlab.ui.control.Switch
        BSslider1Switch_2Label         matlab.ui.control.Label
        BSslider3Switch                matlab.ui.control.Switch
        BSslider3SwitchLabel           matlab.ui.control.Label
        BSslider2Switch                matlab.ui.control.Switch
        BSslider2Switch_2Label         matlab.ui.control.Label
        APDsTab                        matlab.ui.container.Tab
        MapdurationminPanel            matlab.ui.container.Panel
        Button_2                       matlab.ui.control.Button
        MapduratminEditField           matlab.ui.control.NumericEditField
        Go2PointButton                 matlab.ui.control.Button
        MapShowQuantityDropDown        matlab.ui.control.DropDown
        ShowqtyDropDownLabel           matlab.ui.control.Label
        map_image                      matlab.ui.control.UIAxes
        Log                            matlab.ui.control.TextArea
        Comment                        matlab.ui.control.TextArea
        IDLocationPanel                matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        MarkerDownEditField            matlab.ui.control.NumericEditField
        MarkerDownEditFieldLabel       matlab.ui.control.Label
        MarkerUpEditField              matlab.ui.control.NumericEditField
        MarkerUpEditFieldLabel         matlab.ui.control.Label
        NCSpinner                      matlab.ui.control.Spinner
        NCSpinnerLabel                 matlab.ui.control.Label
        MainTab                        matlab.ui.container.TabGroup
        MainGraphTab                   matlab.ui.container.Tab
        conversionSwitch               matlab.ui.control.Switch
        conversionSwitchLabel          matlab.ui.control.Label
        CounterdisplayDropDown         matlab.ui.control.DropDown
        CounterdisplayDropDownLabel    matlab.ui.control.Label
        LargeCounterField              matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        VisuTab                        matlab.ui.container.Tab
        VisusliderONButton             matlab.ui.control.Button
        VisusliderOFFButton            matlab.ui.control.Button
        CustMovieStopButton            matlab.ui.control.StateButton
        CameraImage_2                  matlab.ui.control.UIAxes
        CameraImage                    matlab.ui.control.UIAxes
        SettingsTab                    matlab.ui.container.Tab
        SampleinformationPanel         matlab.ui.container.Panel
        SynthSolvEditField             matlab.ui.control.EditField
        SynthSolvEditFieldLabel        matlab.ui.control.Label
        SynthPolymPercEditField        matlab.ui.control.NumericEditField
        SynthPolymPercEditFieldLabel   matlab.ui.control.Label
        SynthPolymEditField            matlab.ui.control.EditField
        SynthPolymEditFieldLabel       matlab.ui.control.Label
        SynthDilutionEditField         matlab.ui.control.NumericEditField
        SynthDilutionEditFieldLabel    matlab.ui.control.Label
        SynthTempEditField             matlab.ui.control.NumericEditField
        SynthTempEditFieldLabel        matlab.ui.control.Label
        SynthNameEditField             matlab.ui.control.EditField
        SynthNameEditFieldLabel        matlab.ui.control.Label
        SampleOfDropDown               matlab.ui.control.DropDown
        SampleOfDropDownLabel          matlab.ui.control.Label
        SamplePrepDatePicker           matlab.ui.control.DatePicker
        SamplePrepDatePickerLabel      matlab.ui.control.Label
        CCDROIPanel                    matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        SingletrackTab                 matlab.ui.container.Tab
        SingleTrackImage               matlab.ui.control.Image
        heightSpinner                  matlab.ui.control.Spinner
        heightSpinnerLabel             matlab.ui.control.Label
        centralvalueSpinner            matlab.ui.control.Spinner
        centralvalueSpinnerLabel       matlab.ui.control.Label
        ImageTab                       matlab.ui.container.Tab
        ImageImage                     matlab.ui.control.Image
        vendSpinner                    matlab.ui.control.Spinner
        vendSpinnerLabel               matlab.ui.control.Label
        vstartSpinner                  matlab.ui.control.Spinner
        vstartSpinnerLabel             matlab.ui.control.Label
        hendSpinner                    matlab.ui.control.Spinner
        hendSpinnerLabel               matlab.ui.control.Label
        hstartSpinner                  matlab.ui.control.Spinner
        hstartSpinnerLabel             matlab.ui.control.Label
        vbinSpinner                    matlab.ui.control.Spinner
        vbinSpinnerLabel               matlab.ui.control.Label
        hbinSpinner                    matlab.ui.control.Spinner
        hbinSpinnerLabel               matlab.ui.control.Label
        Knob                           matlab.ui.control.DiscreteKnob
        KnobLabel                      matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        LEDKnob                        matlab.ui.control.Knob
        LEDKnobLabel                   matlab.ui.control.Label
        SelectedInstruments            matlab.ui.container.CheckBoxTree
        LightgenerationandcontrolNode  matlab.ui.container.TreeNode
        LEDNode                        matlab.ui.container.TreeNode
        BSsliderNode                   matlab.ui.container.TreeNode
        ShutterNode                    matlab.ui.container.TreeNode
        ExcitationpolarisationNode     matlab.ui.container.TreeNode
        SteeringmirrorNode             matlab.ui.container.TreeNode
        DetectorsNode                  matlab.ui.container.TreeNode
        ZeluxcameraNode                matlab.ui.container.TreeNode
        APDMPDNode                     matlab.ui.container.TreeNode
        CCDiDusNode                    matlab.ui.container.TreeNode
        PowermeterNode                 matlab.ui.container.TreeNode
        LightanalysisNode              matlab.ui.container.TreeNode
        SpectrometerNode               matlab.ui.container.TreeNode
        DetectionpolarisationNode      matlab.ui.container.TreeNode
        TWTBPFilterANode               matlab.ui.container.TreeNode
        SteeringmirrorsPanel           matlab.ui.container.Panel
        FSM_y0EditField                matlab.ui.control.NumericEditField
        FSM_y0EditFieldLabel           matlab.ui.control.Label
        FSM_x0EditField                matlab.ui.control.NumericEditField
        FSM_x0EditFieldLabel           matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        CCDTContextMenu                matlab.ui.container.ContextMenu
        GetCCDTMenu                    matlab.ui.container.Menu
        ExportContextMenu              matlab.ui.container.ContextMenu
        PrinttofigureMenu              matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
    end


    properties (Access = private)
        %%%% Use of private properties should be minimised when possible
        %%%%
        %% For clarity, all private properties must be properly defined here as follows
        % newproperty   % explanation for the need of a property
        clrmp
        init_answer     % answer to initialisation question, to be used for the close function
        ppwd            % le dossier "actuel" à un moment // à voir si vraiment besoin => .exe?
        InstrInfo       % all instrument info, device connections to be used
        AppInfo         % AppInfo to load/reload app
        LastMap         % custom output for testss
        Dot
        hc % in eV*nm
        thismeas
        moreInfo2save
    end

    methods (Access = private)

        function initAllDevices(app)
            % SHOW USER SOME OUTPUT
            add2Log(app,"Initialising all devices...")
            myprogressfig = uifigure('Units','pixels','Position',[680 678 400 108]);
            % high-level function to init all device connections
            myprogressbar = uiprogressdlg(myprogressfig,'Title','Please Wait',...
                'Message','Opening device connections');
            pause(.5)

            % LOAD ALL selected DEVICE CONNECTIONS
            % ADD new instruments here
            instruments = {'CCD iDus', ...
                'Spectrometer', ...
                'LED', ...
                'Steering mirror', ...
                'BS slider', ...
                'Powermeter', ...
                'Zelux camera', ...
                'Shutter',...
                'Excitation polarisation',...
                'Detection polarisation', ...
                'TWTBPFilter A', ...
                'TWTBPFilter B'
                }; % do not change order, add at the end
            app.InstrInfo.instruments = instruments;

            app.InstrInfo.instruments_on = {}; k=1;
            % loop through instruments
            for i=1:length(instruments)
                if i~=43 %i~=2 & i~=4 % do not load instruments twice
                    % instruments{i}
                    if instrument_is_selected(app,instruments{i})
                        switch instruments{i}
                            case 'CCD iDus'
                                if ~strcmp(app.AppInfo.restart_mode,"app and keep spectrograph")


                                    myprogressbar.Message = "Initialising spectrometer and CCD...";

                                    myprogressbar.Value = .2;
                                    %                             setAcqParams(app, 1, 2, 4, 0) % startup params
                                    app.InstrInfo.Spectrometer.loadtime = init_Spectrometer(app);

                                    AcqTime = 0.25; % en s?
                                    readcode = getAndorReadCode(app);
                                    acqcode = getAndorAcqCode(app);
                                    setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, 0)
                                    myprogressbar.Value = .4;
                                end

                            case 'Spectrometer'
                                if ~strcmp(app.AppInfo.restart_mode,"app and keep spectrograph")
                                    if instrument_is_selected(app,'CCD iDus')
                                        app.InstrInfo.instruments_on.name{k} = instruments{i}; k=k+1;
                                        app.InstrInfo.instruments_on.unit{k} = 'nm';
                                        app.InstrInfo.CCD.bkg=0;
                                    end
                                end
                                % a voir %TODO
                            case 'LED'
                                myprogressbar.Message = "Initialising DAQ: LED and steering mirror...";
                                init_DAQ(app);
                                myprogressbar.Value = .6;
                                app.AcquirePicturePushTool.Enable = true;
                                app.InstrInfo.instruments_on.name{k} = instruments{i}; k=k+1;
                            case 'Steering mirror'
                                app.InstrInfo.instruments_on.name{k} = instruments{i};
                                app.InstrInfo.instruments_on.unit{k} = 'V'; k=k+1;
                                % a voir %TODO
                            case 'BS slider'
                                myprogressbar.Message = "Initialising ELL linear...";
                                init_ELL_linear(app);
                                myprogressbar.Value = .7;
                                app.AcquirePicturePushTool.Enable = true;
                            case 'Powermeter'
                                myprogressbar.Message = "Initialising PowerMeter...";
                                ppwd=pwd;
                                cd([pwd '\Interfacing_tests\Matlab-Driver-for-Thorlabs-power-meter-master'])
                                app.InstrInfo.PMeter = init_Photodiode(app);
                                cd(ppwd)
                                myprogressbar.Value = .8;
                            case 'Zelux camera'
                                myprogressbar.Message = "Initialising Zelux camera...";
                                tic
                                [~, app.ppwd] = go2Camfolder();
                                try
                                    % init
                                    app.InstrInfo.CamInfo =init_SoftwareTrigger();
                                catch
                                    % else first close
                                    % how to keep the previous
                                    % app.InstrInfo.CamInfo? maybe save
                                    % app.InstrInfo at the end of the initall
                                    % and if errors reload from file
                                    close_SoftwareTrigger(app.InstrInfo.CamInfo)
                                    app.InstrInfo.CamInfo =init_SoftwareTrigger();
                                    % do the same for the photodiode
                                    % and ELL devices
                                end

                                goback2folder(app.ppwd)
                                app.InstrInfo.CamInfo.loadtime=toc;
                                myprogressbar.Value = 0.9;
                                app.AcquirePicturePushTool.Enable = true;
                                app.AcquireCustMoviePushTool.Enable = true;
                                app.InstrInfo.instruments_on.name{k} = instruments{i}; k=k+1;
                            case 'Shutter'
                                myprogressbar.Message = "Initialising ELL shutter/pol...";
                                init_ELL_shutterpol(app);
                                myprogressbar.Value = 0.95;
                                %app.InstrInfo.instruments_on.name{k} = instruments{i}; k=k+1;
                            case 'Excitation polarisation'
                                if ~instrument_is_selected(app,'Shutter')
                                    init_ELL_shutterpol(app);
                                else
                                    elldevice=app.InstrInfo.ELL_shutterpol;

                                    % pq deux fois?
                                    %                                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                                    %                                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                                    %                                     app.PolarExcReadoutEditField.Value=pos;
                                    %                                     step=5;
                                    %                                     set_jogstep(elldevice.serialdev, elldevice.device_adress_pol, elldevice.device_adress_pol_type,5);
                                    %  app.PolarExcJogStepEditField.Value=step;

                                    app.InstrInfo.instruments_on.name{k} = 'ExcPol';
                                    app.InstrInfo.instruments_on.unit{k} = 'deg';
                                    k=k+1;
                                end
                            case 'Detection polarisation'

                                %                                 ELL_detpol= elliptec_driver(0,"COM5");
                                %                                 pos=get_position(ELL_detpol, 0);
                                %                                 app.PolarDetReadoutEditField.Value=pos;
                                %                                 step = 5;
                                %                                 set_jogstep(ELL_detpol, 0, step);
                                %                                 app.PolarDetJogStepEditField.Value=step;
                                %
                                %                                 app.InstrInfo.ELL_detpol = ELL_detpol;

                                    init_ELL_detpol(app);
                                
                                    elldevice=app.InstrInfo.ELL_detpol;

                                    % pq deux fois?
                                    %                                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                                    %                                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                                    %                                     app.PolarExcReadoutEditField.Value=pos;
                                    %                                     step=5;
                                    %                                     set_jogstep(elldevice.serialdev, elldevice.device_adress_pol, elldevice.device_adress_pol_type,5);
                                    %  app.PolarExcJogStepEditField.Value=step;

                                    app.InstrInfo.instruments_on.name{k} = 'DetPol';
                                    app.InstrInfo.instruments_on.unit{k} = 'deg';


                                    % entrer le bon port COM
                                    %                                 app.InstrInfo.ELL_detpol.serialdev=visadev("COM6");
                                    %                                 app.InstrInfo.ELL_detpol.device_adress='0';
                                    %                                 app.InstrInfo.ELL_detpol.type='RM';
                                    % elldevice=app.InstrInfo.ELL_detpol;
                                    % % pq deux fois?
                                    %                 pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                                    %                 pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                                    %                 app.PolarDetReadoutEditField.Value=pos;
                                    %                 step=5;
                                    %                 set_jogstep(elldevice.serialdev, elldevice.device_adress, elldevice.type5);
                                    %                 app.PolarDetJogStepEditField.Value=step;
                                    app.InstrInfo.instruments_on.name{k} = 'DetPol';
                                    app.InstrInfo.instruments_on.unit{k} = 'deg';
                                    k=k+1;
                                case 'TWTBPFilter A'
                                    myprogressbar.Message = "Initialising ELL TWTBP1...";
                                    init_TWTBP1(app);
                                    myprogressbar.Value = 1.;
                                    app.InstrInfo.instruments_on.name{k} = instruments{i};
                                    app.InstrInfo.instruments_on.unit{k} = 'nm'; k=k+1;
                                    app.InstrInfo.instruments_on.name{k} = 'BP1A';
                                    app.InstrInfo.instruments_on.unit{k} = 'deg'; k=k+1;
                                    app.InstrInfo.instruments_on.name{k} = 'BP2A';
                                    app.InstrInfo.instruments_on.unit{k} = 'deg'; k=k+1;
                                    app.InstrInfo.instruments_on.name{k} = 'CPA';
                                    app.InstrInfo.instruments_on.unit{k} = 'deg'; k=k+1;
                                case 'TWTBPFilter B'

                                    myprogressbar.Message = "Initialising ELL TWTBP2...";

                                end
                            else
                                % turn off buttons for absent devices
                                switch instruments{i}
                                    case 'CCD iDus'
                                        set(app.CCDPanel.Children,'Enable',false)
                                    case 'Spectrometer'
                                        set(app.MonochromatorPanel.Children,'Enable',false)
                                    case 'LED'
                                        app.AcquirePicturePushTool.Enable = false;
                                    case 'Steering mirror'
                                        set(app.FSMTab.Children,'Enable',false)
                                    case 'BS slider'
                                        app.AcquirePicturePushTool.Enable = false;
                                    case 'Powermeter'
                                        app.ExcPow.Enable=false;
                                        app.GetPower.Enable=false;
                                    case 'Zelux camera'
                                        app.AcquirePicturePushTool.Enable = false;
                                        app.AcquireCustMoviePushTool.Enable = false;
                                    case 'Shutter'
                                        app.shutter_tool.Enable = false;
                                    case 'Excitation polarisation'
                                        set(app.ExcPolPanel.Children,'Enable',false)
                                    case 'Detection polarisation'
                                        set(app.DetPolPanel.Children,'Enable',false)
                                    case 'TWTBPFilter A'
                                        set(app.FiltersetAPanel.Children,'Enable',false)
                                    case 'TWTBPFilter B'
                                        set(app.FiltersetBPanel.Children,'Enable',false)
                                end
                        end
                    end
                end
                if isfield(app.InstrInfo.instruments_on,'name')
                    app.instrDropDown.Items = app.InstrInfo.instruments_on.name;
                end


                if instrument_is_selected(app,'CCD iDus')
                    [ret, app.InstrInfo.CCD.T] = GetTemperature();
                    add2Log(app,num2str(app.InstrInfo.CCD.T));

                    if app.InstrInfo.CCD.T < -77
                        app.CCDTLamp.Color=[0 1 0];
                    else
                        app.CCDTLamp.Color= [0.5 1 0];
                    end
                end

                disp('All devices connected')
                add2Log(app,"All devices connected");

                close(myprogressfig)
            end

            function [loadtime, deviceCount, serial, numpx] = init_Spectrometer(app)
                tic
                % initialise connection with spectrometer Shamrock

                % initialise connection
                [ret] = ATSpectrographInitialize('');
                ATSpectrographCheckError(ret);


                %init CCD first
                app.InstrInfo.CCD.loadtime = init_CCD(app);


                % check num devices = 1
                % but beware devices are called with indices 0 to N
                [ret, deviceCount] = ATSpectrographGetNumberDevices();
                ATSpectrographCheckWarning(ret);

                if deviceCount~=1 & deviceCount~=0
                    error('more than 1 spectrometer found')
                else
                    i=0;
                end


                %             for i=0:(deviceCount-1)
                % get serial number to check connection
                [ret, spectro.serial] = ATSpectrographGetSerialNumber(0, 256);
                ATSpectrographCheckWarning(ret);

                [ret, spectro.noGratings] = ATSpectrographGetNumberGratings(0);
                ATSpectrographCheckWarning(ret);

                [ret, spectro.currGrating] = ATSpectrographGetGrating(0);
                ATSpectrographCheckWarning(ret);

                switch spectro.currGrating
                    case 1
                        app.GratingDropDown.Value='150';
                    case 2
                        app.GratingDropDown.Value='600';
                    case 3
                        app.GratingDropDown.Value='1200';
                end

                for grat = 1:3
                    [ret, spectro.currGratingInfo(grat).lines, spectro.currGratingInfo(grat).blaze, spectro.currGratingInfo(grat).home, spectro.currGratingInfo(grat).offset] = ATSpectrographGetGratingInfo(0,grat,256);
                    ATSpectrographCheckWarning(ret);
                end

                [ret, spectro.currWL] = ATSpectrographGetWavelength(0);
                ATSpectrographCheckWarning(ret);

                app.WavelengthnmEditField.Value = spectro.currWL;

                [ret, spectro.WLmin_bef, spectro.WLmax_bef] = ATSpectrographGetWavelengthLimits(0,spectro.currGrating);
                ATSpectrographCheckWarning(ret);

                [ret] = ATSpectrographSetPixelWidth(0,app.InstrInfo.CCD.PixelSize);
                ATSpectrographCheckWarning(ret);

                [ret, spectro.isshutter] = ATSpectrographShutterIsPresent(0);
                ATSpectrographCheckWarning(ret);


                [ret, spectro.isflipperinput] = ATSpectrographFlipperMirrorIsPresent(0,1);
                ATSpectrographCheckWarning(ret);

                [ret, spectro.isflipperoutput] = ATSpectrographFlipperMirrorIsPresent(0,2);
                ATSpectrographCheckWarning(ret);


                [ret] = ATSpectrographSetNumberPixels(0,app.InstrInfo.CCD.xpixels);
                ATSpectrographCheckWarning(ret);

                [ret, spectro.calib] = ATSpectrographGetCalibration(0,app.InstrInfo.CCD.xpixels);
                ATSpectrographCheckWarning(ret);

                [ret, spectro.WLmin, spectro.WLmax] = ATSpectrographGetWavelengthLimits(0,spectro.currGrating);
                ATSpectrographCheckWarning(ret);


                [ret, spectro.low, spectro.high] = ATSpectrographGetCCDLimits(0,1);
                ATSpectrographCheckWarning(ret);

                [ret, port] = ATSpectrographGetFlipperMirror(0,1);
                ATSpectrographCheckError(ret);

                switch port
                    case 0
                        new_input='Direct';
                    case 1
                        new_input='Side';
                end

                app.InputportDropDown.Value=new_input;

                [ret, port] = ATSpectrographGetFlipperMirror(0,2);
                ATSpectrographCheckError(ret);

                switch port
                    case 0
                        new_output='CCD';
                    case 1
                        new_output='Free space';
                end

                app.OutputportDropDown.Value=new_output;


                for i=0:2
                    [ret, shutterbncpossible] = ATSpectrographIsShutterModePossible(0,i);
                    %              ATSpectrographCheckError(ret)
                    spectro.shutterbncpossible(i+1) = shutterbncpossible;
                end
                [ret] = ATSpectrographSetShutter(0,2);
                %              ATSpectrographCheckError(ret)
                spectro.shuttermode(2) = 2;


                [ret, shuttermode] = ATSpectrographGetShutter(0);
                %              ATSpectrographCheckError(ret)
                spectro.shuttermode(1) = shuttermode;
                loadtime=toc;
                app.InstrInfo.Spectrometer = spectro;
            end


            function loadtime = init_CCD(app)
                tic
                % initialise connection with Andor CCD

                % initialise connection
                ret=AndorInitialize('');
                add2Log(app,myCheckWarning(ret));

                [ret,app.InstrInfo.CCD.Capabilities]=GetCapabilities;
                CheckWarning(ret);

                % check if CCD is cooled and turn on cooling
                [ret, ison] = IsCoolerOn();
                add2Log(app,myCheckWarning(ret));

                app.InstrInfo.CCD.SetT = -80;
                [ret] = SetTemperature(app.InstrInfo.CCD.SetT);
                %             add2Log(app,myCheckWarning(ret));

                if ~ison
                    [ret]=CoolerON();                             %   Turn on temperature cooler
                end
                [ret, app.InstrInfo.CCD.CoolingOn] = IsCoolerOn();
                %             add2Log(app,myCheckWarning(ret));

                [ret, app.InstrInfo.CCD.T] = GetTemperature();
                add2Log(app,num2str(app.InstrInfo.CCD.T));

                if app.InstrInfo.CCD.CoolingOn~=1 % au cas ou
                    add2Log(app,'Coolor not on')
                end

                [ret, app.InstrInfo.CCD.PixelSize] = GetPixelSize();
                %             add2Log(app,myCheckWarning(ret));

                [ret, app.InstrInfo.CCD.xpixels, app.InstrInfo.CCD.ypixels] = GetDetector();
                %             add2Log(app,myCheckWarning(ret));
                loadtime=toc;
            end

            function setAcqParams(app, acqmode, time, readmode, triggermode)
                [ret]=SetAcquisitionMode(acqmode);                  %   Set acquisition mode; 1 for Single Scan
                CheckWarning(ret);
                [ret]=SetExposureTime(double(time));                  %   Set exposure time in second
                CheckWarning(ret);
                [ret]=SetReadMode(readmode);                         %   Set read mode; 4 for Image
                CheckWarning(ret);
                [ret]=SetTriggerMode(triggermode);                      %   Set internal trigger mode
                CheckWarning(ret);
            end

            function close_Spectrometer(app)
                [ret] = ATSpectrographClose();
                ATSpectrographCheckWarning(ret);
            end

            function close_CCD(app)
                [ret]=AndorShutDown;
                CheckWarning(ret);
            end

            function closeAllDevices(app)
                if strcmp(app.init_answer,'Yes')
                    % Some user output
                    add2Log(app,"Closing all devices...");
                    myprogressfig = uifigure('Units','pixels','Position',[680 678 400 108]);
                    % high-level function to init all device connections
                    myprogressbar = uiprogressdlg(myprogressfig,'Title','Please Wait',...
                        'Message','Closing device connections');
                    pause(0.5)


                    % loop through instruments and close connections
                    for i=1:length(app.InstrInfo.instruments)
                        if instrument_is_selected(app, app.InstrInfo.instruments{i})
                            myprogressbar.Message = ['Closing' app.InstrInfo.instruments{i}];
                            add2Log(app,["Closing" + app.InstrInfo.instruments{i}])
                            switch app.InstrInfo.instruments{i}
                                case 'CCD IDus'
                                    if ~strcmp(app.AppInfo.restart_mode,"app and keep spectrograph")
                                        close_CCD(app);
                                    end
                                case 'Spectrometer'
                                    if ~strcmp(app.AppInfo.restart_mode,"app and keep spectrograph")
                                        close_Spectrometer(app);
                                    end
                                case 'LED'
                                    try
                                        flush(app.InstrInfo.DAQInfo.dq)
                                        delete(app.InstrInfo.DAQInfo.dq)
                                    catch
                                    end
                                case 'Steering mirror'
                                    try
                                        flush(app.InstrInfo.DAQInfo.dq)
                                        delete(app.InstrInfo.DAQInfo.dq)
                                    catch
                                    end
                                case 'BS slider'
                                    % flush(app.InstrInfo.ELL_linear.serialdev)
                                    delete(app.InstrInfo.ELL_linear.serialdev)
                                    clear app.InstrInfo.ELL_linear.serialdev
                                case 'Powermeter'
                                    close_Photodiode(app);
                                case 'Shutter'
                                    try
                                        toggle_shutter(app, 'close', 0)
                                        delete(app.InstrInfo.ELL_shutterpol)
                                    catch
                                    end
                                    %                                 flush(app.InstrInfo.ELL_shutterpol)
                                case 'Zelux camera'
                                    [~, app.ppwd] = go2Camfolder();
                                    try
                                        close_SoftwareTrigger(app.InstrInfo.CamInfo)
                                    catch
                                    end
                                    goback2folder(app.ppwd);
                                case 'Excitation polarisation'
                                    try
                                        delete(app.InstrInfo.ELL_shutterpol)
                                    catch
                                    end
                                case 'Detection polarisation'
                                    try
                                        delete(app.InstrInfo.ELL_detpol)
                                    catch
                                    end

                                case 'TWTBPFilter A'
                                    try
                                        %                                 for i=1:length(app.InstrInfo.ELL_rotA)
                                        %                                     delete(app.InstrInfo.ELL_rotA(i).serialdev)
                                        % %                                 end
                                        %                                 clear app.InstrInfo.ELL_rotA
                                        delete(app.InstrInfo.ELL_TWTBP1)
                                    catch
                                    end
                            end
                        end
                    end

                    clear app.InstrInfo
                    myprogressbar.Value = 1;
                    myprogressbar.Message = "All done!";
                    pause(0.3)
                    beep; beep; beep;
                    close(myprogressfig)
                end
            end

            function results = DummyPlot(app)

                tic
                [ret]=SetShutter(1, 1, 0, 0);                 %   Open Shutter
                CheckWarning(ret);
                [ret,XPixels, YPixels]=GetDetector();         %   Get the CCD size
                CheckWarning(ret);
                [ret]=SetImage(1, 1, 1, XPixels, 1, YPixels); %   Set the image size
                CheckWarning(ret);

                toc
                tic
                disp('Starting Acquisition');


                [ret] = StartAcquisition();
                CheckWarning(ret);
                toc
                disp('waitnow')
                tic
                [ret] = WaitForAcquisition();
                CheckWarning(ret);

                [ret, imageData] = GetMostRecentImage(XPixels * YPixels);
                CheckWarning(ret);
                toc
                if ret == atmcd.DRV_SUCCESS
                    %display the acquired image
                    imagesc(app.UIAxes,flipdim(transpose(reshape(imageData, XPixels, YPixels)),1));
                    colormap(app.UIAxes,gray);
                    drawnow;
                end
                colorbar
                %% END:DUMMY

                [ret]=AbortAcquisition;
                CheckWarning(ret);
                [ret]=SetShutter(1, 2, 1, 1);
                CheckWarning(ret);

            end

            function results = FSM_Jog(app, whichbutton)
                stepsize = micron2volt(app, app.FSMStepEditField.Value);

            end

            function loadtime = init_DAQ(app)
                tic
                app.InstrInfo.DAQInfo.dq = daq("ni");
                app.InstrInfo.DAQInfo.ch0=addoutput(app.InstrInfo.DAQInfo.dq, "cDAQ1Mod1", "ao0", "Voltage"); %stiring mirror x
                app.InstrInfo.DAQInfo.ch1=addoutput(app.InstrInfo.DAQInfo.dq, "cDAQ1Mod1", "ao1", "Voltage"); %stiring mirror y
                % app.InstrInfo.DAQInfo.ch2=addoutput(app.InstrInfo.DAQInfo.dq, "cDAQ1Mod1", "ao2", "Voltage"); %CCD Trigger
                app.InstrInfo.DAQInfo.ch2=addoutput(app.InstrInfo.DAQInfo.dq, "cDAQ1Mod1", "ao2", "Voltage"); %LED

                % define "zero" initial position for FSM
                FSM_x0 = micron2volt(app, app.FSM_x0EditField.Value);
                FSM_y0 = micron2volt(app, app.FSM_y0EditField.Value);
                send_DAQ_output(app,FSM_x0,FSM_y0,0)
                loadtime=toc;
            end

            function isvalid = test_if_valid(app,V_x,V_y, V_LED)
                if micron2volt(app, abs(V_x))>10 | micron2volt(app, abs(V_y))>10 | V_LED>5 | V_LED<0
                    isvalid=false;
                else
                    isvalid=true;
                end
            end

            function results = send_DAQ_output(app,V_x,V_y, V_LED)

                if test_if_valid(app,V_x,V_y, V_LED)
                    data(:,1)=[V_x V_x]';
                    data(:,2)=[V_y V_y]';
                    %data(:,3)=[V_CCD V_CCD]';
                    data(:,3)=[V_LED V_LED]';
                    try
                        write(app.InstrInfo.DAQInfo.dq, data);
                        % app.LEDKnob.Value=V_LED;
                        app.FSM_xREditField.Value=volt2micron(app, V_x);
                        app.FSM_yREditField.Value=volt2micron(app, V_y);
                        pause(0.001)
                    catch
                    end
                    %  pause(0.01);
                else
                    add2Log(app, 'DAQ output out of range')
                end

            end

            function [test_meter, loadtime] = init_Photodiode(app)
                tic
                meter_list=ThorlabsPowerMeter;                              % Initiate the meter_list
                DeviceDescription=meter_list.listdevices;               	% List available device(s)
                test_meter=meter_list.connect(DeviceDescription);           % Connect single/the first device
                test_meter.setWaveLength(450);                              % Set sensor wavelength
                loadtime=toc;
            end

            function results = close_Photodiode(app)
                try
                    app.InstrInfo.PMeter.disconnect;
                catch
                    % load previous app.InstrInfo to disconnect anyway
                end
            end

            function acqcode = getAndorAcqCode(app)
                switch app.AndorAcqModeDropDown.Value
                    case 'Single scan'
                        acqcode = 1;
                    case 'Accumulate'
                        acqcode = 2;
                    case 'Kinetic series'
                        acqcode = 3;
                    case 'Continuous'
                        acqcode = 5;
                    otherwise
                        error('Acquisition mode unknown')
                end
            end

            function readcode =  getAndorReadCode(app)
                switch app.AndorReadoutModeDropDown.Value;
                    case 'Full Vertical Binning'
                        readcode = 0;
                    case 'Multi-track'
                        readcode = 1;
                    case 'Random multi-track'
                        readcode = 2;
                    case 'Single-track'
                        readcode = 3;
                    case 'Image'
                        readcode = 4;
                    otherwise
                        error('Readout mode unknown')
                end
            end

            function init_ELL_linear(app)
                app.InstrInfo.ELL_linear.serialdev=visadev("COM3");
                app.InstrInfo.ELL_linear.device_adress='0';
                app.InstrInfo.ELL_linear.type='L';
            end

            function result = instrument_is_selected(app, instrumentname)
                result = any(arrayfun(@(x) strcmp(x.Text,instrumentname), app.SelectedInstruments.CheckedNodes));
            end

            function GraphicalInterfaceStartup(app,order)
                % Set graphical interface here

                switch order

                    case 'PuOr'
                        % default colormap and colororder
                        app.clrmp = brewermap(255,'PuOr');
                        app.UIAxes.Colormap = app.clrmp;
                        app.UIAxes.Colormap = app.clrmp;
                        app.UIAxes.ColorOrder = flipud(brewermap(2,'PuOr'));

                    case 'OrPu'
                        % default colormap and colororder
                        app.clrmp = flipud(brewermap(255,'PuOr'));
                        app.UIAxes.Colormap = app.clrmp;
                        app.UIAxes.Colormap = app.clrmp;
                        app.UIAxes.ColorOrder = brewermap(2,'PuOr');
                end
            end

            function init_ELL_shutterpol(app)
                address = [1 2] % 1: shutter 2: polariser
                ELL_shutterpol= elliptec_driver(address,"COM4");

                app.InstrInfo.ELL_shutterpol = ELL_shutterpol;

                pos=get_position(ELL_shutterpol, 2);
                app.PolarExcReadoutEditField.Value = pos;

                step=5;
                set_jogstep(ELL_shutterpol, 2,step);
                app.PolarExcJogStepEditField.Value=step;

                %             app.InstrInfo.ELL_shutterpol.serialdev=visadev("COM4");
                %             app.InstrInfo.ELL_shutterpol.device_adress_shutter='1'; %shutter
                %             app.InstrInfo.ELL_shutterpol.device_adress_pol='2'; %polariser
                %             app.InstrInfo.ELL_shutterpol.device_adress_pol_type ='RM'; %RM
                %             app.InstrInfo.ELL_shutterpol.status=0; %shutter is on
            end

            function init_ELL_detpol(app)
                address = [0];
                ELL_detpol = elliptec_driver(address, "COM5");
                app.InstrInfo.ELL_detpol = ELL_detpol;

                pos=get_position(ELL_detpol, address);
                app.PolarDetReadoutEditField.Value = pos;

                step=5;
                set_jogstep(ELL_detpol, address,step);
                app.PolarDetJogStepEditField.Value=step;

            end


            function add2Log(app,additionaltext)
                % function to add text to the log output

                tempo = app.Log.Value;
                app.Log.Value(1) = { [char(datetime("now",'Format','yy-MM-dd HH:mm:ss')) ' - ' convertStringsToChars( additionaltext )]};
                app.Log.Value(2:length(tempo)+1) = tempo;
                % clean log ?
                % if length(app.Log.Value)>4
                %   app.Log.Value = app.Log.Value(1:4);
                % end
            end

            function acquire_image(app)
                % acquire image with Zelux camera
                app.ppwd = pwd;
                [~, app.ppwd] = go2Camfolder();
                myImage = acq_SoftwareTrigger(app.InstrInfo.CamInfo); % take a pic w/ ThorCam
                imagesc(app.CameraImage, myImage)%.ImageSource= "latestimage.png";
                xlim(app.CameraImage,[1 1440]); ylim(app.CameraImage,[1 1080]);
                drawnow
                assignin('base','x',app.CameraImage)
                goback2folder(app.ppwd);
            end


            function acquire_Camera(app)
                %%% New version 230314
                %%% All in => continuous works somehow with a normal loop
                %%% and other callbacks can be trigerred in the app

                tlCamera = app.InstrInfo.CamInfo.tlCamera;
                tlCameraSDK = app.InstrInfo.CamInfo.tlCameraSDK;
                serialNumbers = app.InstrInfo.CamInfo.serialNumbers;
                isColorCamera = app.InstrInfo.CamInfo.isColorCamera;
                maxPixelValue = app.InstrInfo.CamInfo.maxPixelValue;
                demosaicker = app.InstrInfo.CamInfo.demosaicker;
                bitDepth = app.InstrInfo.CamInfo.bitDepth;
                standardRGBColorProcessor = app.InstrInfo.CamInfo.standardRGBColorProcessor;
                colorFilterArrayPhase = app.InstrInfo.CamInfo.colorFilterArrayPhase;
                colorProcessorSDK = app.InstrInfo.CamInfo.colorProcessorSDK;

                %
                %                         numberOfFramesToAcquire = 200;
                %                         for iloop = 1:numberOfFramesToAcquire
                while app.CustMovieStopButton.Value==0
                    if app.CustMovieStopButton.Value==true
                        app.CustMovieStopButton.Value=false;
                        break %
                    end
                    % Each call to Start sends a software trigger.
                    tlCamera.IssueSoftwareTrigger;

                    % Wait for image buffer to be filled to prevent sending too many
                    % software triggers.
                    while (tlCamera.NumberOfQueuedFrames == 0)
                        pause(0.01);
                    end

                    % If data processing in Matlab falls behind camera image
                    % acquisition, the FIFO image frame buffer could be filled up,
                    % which would result in missed frames.
                    if (tlCamera.NumberOfQueuedFrames > 1)
                        disp(['Data processing falling behind acquisition. ' num2str(tlCamera.NumberOfQueuedFrames) ' remains']);
                    end

                    % Get the pending image frame.
                    imageFrame = tlCamera.GetPendingFrameOrNull;
                    if ~isempty(imageFrame)
                        % For color images, the image data is in BGR format.
                        imageData = imageFrame.ImageData.ImageData_monoOrBGR;

                        disp(['Image frame number: ' num2str(imageFrame.FrameNumber)]);

                        % TODO: custom image processing code goes here
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        if (isColorCamera)
                            % Allocate memory for demosaicking output.
                            demosaickedImageData = NET.createArray('System.UInt16',imageHeight * imageWidth * 3);
                            colorFormat = Thorlabs.TSI.ColorInterfaces.ColorFormat.BGRPixel;
                            % Demosaic the Bayer patterned image from the camera.
                            demosaicker.Demosaic(imageWidth, imageHeight, int32(0), int32(0), colorFilterArrayPhase,...
                                colorFormat, Thorlabs.TSI.ColorInterfaces.ColorSensorType.Bayer,...
                                bitDepth, imageData, demosaickedImageData);

                            % Allocate memory for color processed image.
                            processedImageData = NET.createArray('System.UInt16',imageHeight * imageWidth * 3);

                            % Use the color processor to perform color transform.
                            standardRGBColorProcessor.Transform48To48(demosaickedImageData, colorFormat,...
                                uint16(0), uint16(maxPixelValue), uint16(0), uint16(maxPixelValue),...
                                uint16(0), uint16(maxPixelValue), int32(0), int32(0), int32(0), processedImageData, colorFormat);

                            % Display the color image
                            imageColor = reshape(uint16(processedImageData), [3, imageWidth, imageHeight]);
                            imageColor = double(permute(imageColor,[3 2 1]));
                            imageColor = flip(imageColor,3);    % Change from BGR to RGB
                            %figure(1),image(imageColor/maxPixelValue)%, %colorbar
                            %set(gca,'visible','off')

                        else
                            imageData2D = reshape(uint16(imageData), [imageWidth, imageHeight]);
                            %figure(1),imagesc(imageData2D'), colormap(gray), colorbar
                        end
                    end

                    % Release the image frame
                    delete(imageFrame);
                    drawnow;
                    myNormalisedImage = imageColor/maxPixelValue;

                    assignin('base','x',app.CameraImage)


                    assignin('base','z',imageColor)
                    imagesc(app.CameraImage, myNormalisedImage);
                    drawnow;
                end


            end

            function set_app_state(app,newstate)
                % control the main state LED app.StatusLamp
                switch newstate
                    case {'busy','Busy',0}
                        app.StatusLamp.Color = [255 140 0]./255;
                        app.PauseTool.Enable = true;
                        app.StopTool.Enable = true;
                        app.AppStateToggleTool.State=true; app.AppStateToggleTool.Enable=false;
                        app.AppStateToggleTool.Icon = [app.AppInfo.basepath_app + "\design\icons\Diapositive14.PNG"];
                        set(app.CARODOTManipUIFigure,'Pointer','watch'); drawnow
                    case {'idle', 'Idle',1}
                        app.StatusLamp.Color = [0 255 0]./255;
                        app.PauseTool.Enable = false;
                        app.PauseTool.State = false;
                        app.StopTool.Enable = false;
                        app.StopTool.State = false;
                        app.AppStateToggleTool.State=false; app.AppStateToggleTool.Enable=false;
                        app.AppStateToggleTool.Icon = [app.AppInfo.basepath_app + "\design\icons\Diapositive15.PNG"];
                        set(app.CARODOTManipUIFigure,'Pointer','arrow');
                end
                pause(0.01)
            end

            function results = acquire_CCD(app)
                % all acquisition parameters are available in the app
                % here we treat the actual cases

                % params: acqmode, readmode, acqtime, accum, cycetime
            end

            function results = volt2micron(app, volt) %conversion from volt to micron
                %    results=volt*10./0.15;
                results=volt.*10./0.15.*1.65;
            end

            function results = micron2volt(app, micron)
                %  results=micron*0.15/10.;
                results=micron*0.15/(10.*1.65);
            end

            function results = do_map(app, x_size, y_size, x_0, y_0, step_map)
                app.thismeas = 'XYMap';

%                 x_size=x_size_um./step_map;
%                 y_size=y_size_um./step_map;

                tic
                set_app_state(app,'busy')

                readcode = 3; %single-track
                acqcode=5; %run till abort
                trigmode=0;
                setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, trigmode)

                [ret]=SetShutter(1,0, 0, 0);                 %   Open Shutter IN FULLY AUTO
                CheckWarning(ret);

                [ret]=SetSingleTrack(app.centralvalueSpinner.Value, app.heightSpinner.Value);
                CheckWarning(ret);

                [ret,xpixels, ~] = GetDetector;           %   Get the CCD size
                CheckWarning(ret);
                app.InstrInfo.Spectrometer.xpixels= xpixels;


                LaMap = NaN(y_size./step_map+1,x_size./step_map+1,xpixels);
                LesPos = NaN(y_size./step_map+1,x_size./step_map+1,2);

                count = 0;

                bkg=acquire_background(app,app.backgroundCheckBox.Value);
                [ret] = StartAcquisition();
                CheckWarning(ret);


                % create x and y pos list beforehand
                xmap = -[0:step_map:x_size]+x_0+x_size./2;

                ymap = [0:step_map:y_size] + y_0 - y_size./2;


                for j=1:size(xmap')
                    for i=1:size(ymap')

                     %   if rem(count,2)==0 %mapping as a snake => OK but why?
                            %                             x = x_0+ x_size./2-(i-1)*step_map; %positions in microns
                            %                             y = y_0- y_size./2+(j-1)*step_map;
                            x = xmap(i);
                            y = ymap(j);
                       % else
                            %                             x = x_0+ x_size./2-(x_size-1)*step_map+(i-1)*step_map;
                            %                             y = y_0- y_size./2+(j-1)*step_map;
%                             x = xmap(i);
%                             y = ymap(j);
                      %  end

                        send_DAQ_output(app, micron2volt(app, x), micron2volt(app,y), 0);

                        [ret] = WaitForAcquisition();
                        CheckWarning(ret);

                        [ret, imageData] = GetMostRecentImage(xpixels);
                        CheckWarning(ret);

                       % if rem(count,2)==0
                            LaMap(j,i,:) = imageData-bkg;
                            LesPos(j,i,:) = [x y]; %positions x y in microns
                      %  else
                          %  LaMap(j,x_size+1-i,:) = imageData-bkg;
                          %  LesPos(j,x_size+1-i,:) = [x y];
                      %  end


                        plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, imageData-bkg);

                        switch app.MapShowQuantityDropDown.Value
                            case 'Amplitude'
                                [Z, ~] = max(LaMap,[],3);
                            case 'Intensity'
                                Z = sum(LaMap,3);
                            case 'Wavelength'
                                [~, id] = max(LaMap,[],3);
                                Z = app.InstrInfo.Spectrometer.calib(id);
                        end

                        imagesc(app.map_image, flipud(xmap),ymap, Z);
                        axis(app.map_image,[-Inf Inf -Inf Inf])
                        set(app.map_image, 'Layer', 'top')
                        c=colorbar(app.map_image,'SouthOutside');
                        c.Color='w';
                      set(app.map_image,'XDir', 'reverse')
                      pbaspect(app.map_image,[1 1 1])

                        % check pause/stop
                        stopexp = check_if_pause_or_stop(app);
                        if stopexp
                            break
                        end

                    end
                    if stopexp
                        break
                    end
                    count=count+1;
                end

                [ret]=AbortAcquisition;
                CheckWarning(ret);

                app.LastMap.LaMap = LaMap;
                app.LastMap.LesPos = LesPos;

                toggle_shutter(app,'close',0)
                % save if autosave
                if app.AutosaveToggleTool.State%app.AutosaveCheckBox.Value
                    save_data(app,app.thismeas)
                end
                set_app_state(app,'idle')
                toc
            end

            function toggle_shutter(app, fstate, t_pause)
                if instrument_is_selected(app,'Shutter')
                    elldevice=app.InstrInfo.ELL_shutterpol;
                    %                 pos=get_position(elldevice, 1);
                    switch fstate %final state
                        case 'open' %open the shutter
                            dir='fwd';
                            app.shutter_tool.Icon='Diapositive9.PNG';
                            %                         step_forward(elldevice.serialdev,elldevice.device_adress_shutter,'L');
                            %                         elldevice.status=1; % shutter is off
                        case 'close' %close the shutter
                            dir='bwd';
                            %                         step_backward(elldevice.serialdev,elldevice.device_adress_shutter,'L');
                            %                         elldevice.status=0; % shutter is on
                            app.shutter_tool.Icon='Diapositive8.PNG';
                    end
                    %                 app.InstrInfo.ELL_shutterpol=elldevice;
                    jog(elldevice, 1,dir);
                    pause(t_pause);
                end
            end

            function bkg = acquire_background(app,istrue)
                toggle_shutter(app,'close',0.01);
                %    toggle_shutter(app,0.01);

                %%% Decide on need for background

                newbackgroundrequested=false;
                if istrue & ( isnan(app.InstrInfo.CCD.bkg) | ~any(app.InstrInfo.CCD.bkg))
                    add2Log(app,'Acquiring background')
                    newbackgroundrequested=true;
                else if istrue & ~isnan(app.InstrInfo.CCD.bkg)
                        add2Log(app,'background already acquired')

                        bkg = app.InstrInfo.CCD.bkg;
                else
                    add2Log(app,'No background requested')

                    bkg=0;
                end
                end
                drawnow


                %%% act upon the decision and set bkg value
                if newbackgroundrequested

                    switch app.DetectorDropDown.Value
                        case 'CCD1D'
                            [ret] = StartAcquisition();
                            CheckWarning(ret);
                            [ret] = WaitForAcquisition();
                            CheckWarning(ret);
                            [ret, bkg] = GetMostRecentImage(app.InstrInfo.Spectrometer.xpixels);
                            CheckWarning(ret);
                            [ret]=AbortAcquisition;
                            CheckWarning(ret);

                            app.InstrInfo.CCD.bkg = bkg;
                        case 'CCD2D'
                            [ret] = StartAcquisition();
                            CheckWarning(ret);
                            [ret] = WaitForAcquisition();
                            CheckWarning(ret);
                            [ret, bkg] = GetMostRecentImage(app.InstrInfo.Spectrometer.xpixels*app.InstrInfo.Spectrometer.ypixels);
                            CheckWarning(ret);
                            [ret]=AbortAcquisition;
                            CheckWarning(ret);

                            app.InstrInfo.CCD.bkg = reshape(bkg, app.InstrInfo.Spectrometer.xpixels, app.InstrInfo.Spectrometer.ypixels);
                        case {'APDA', 'APDB', 'APDA+B'}

                        case 'PD1'

                        case 'BSI'
                    end

                end
                toggle_shutter(app,'open',0.01);
            end

            function [x, y] = selectDataPoints(app, ax)
                roi = drawpoint(ax);
                x = roi.Position(1);
                y = roi.Position(2);
            end


            function switch_eV_nm(app)
                value = app.conversionSwitch.Value;
                x=app.InstrInfo.Spectrometer.calib;
                switch value
                    case 'nm'
                        app.UIAxes.Children(1).XData=x;
                        xlabel(app.UIAxes, 'Wavelength (nm)')
                    case 'eV'
                        app.UIAxes.Children(1).XData=app.hc./x;
                        xlabel(app.UIAxes, 'Energy (eV)')
                end
            end


            function results = delete_background(app)
                app.InstrInfo.CCD.bkg = NaN;
            end

            function stopexp = check_if_pause_or_stop(app)
                switch app.PauseTool.State
                    case true
                        add2Log(app,'Measurement paused')
                        while app.PauseTool.State
                            pause(0.1)
                        end
                        add2Log(app,'Measurement resumed')
                    case false

                end

                switch app.StopTool.State
                    case true
                        % STOP
                        stopexp = 1;
                        add2Log(app,'Measurement/Acquisition aborted')
                    case false
                        stopexp=0;
                        % continue
                end
            end

            function results = do_map_2(app, x_size, y_size, x_0, y_0, step_map)

                set_app_state(app,'busy')
                acqcode=5;
                readcode = 3; %single-track
                trigmode=0;
                setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, trigmode)
                %
                [ret]=SetShutter(1,0, 0, 0);                 %   Open Shutter IN FULLY AUTO
                CheckWarning(ret);

                [ret]=SetSingleTrack(app.centralvalueSpinner.Value, app.heightSpinner.Value);
                CheckWarning(ret);

                [ret,xpixels, ~] = GetDetector;           %   Get the CCD size
                CheckWarning(ret);
                app.InstrInfo.Spectrometer.xpixels= xpixels;

                LaMap_1 = zeros(1,x_size);
                LaMap_2=zeros(1,y_size);
                LesPos = NaN(y_size,x_size,2);
                Map_end= zeros(y_size,x_size);
                Map_max=zeros(2,x_size)
                threshold=4.1e3;
                %   count = 0;

                if app.backgroundCheckBox.Value==1  % acquire mon background
                    [ret] = StartAcquisition();
                    CheckWarning(ret);
                    bkg=mean(acquire_background(app,app.backgroundCheckBox.Value))
                    assignin('base', 'bkg',bkg)
                else

                    bkg=acquire_background(app,app.backgroundCheckBox.Value);
                    %      toggle_shutter(app,0.01)
                    [ret] = StartAcquisition();
                    CheckWarning(ret);
                end

                for k=1:2 %parcourir 2x la map horizontaleemnt puis verticalement
                    for j=1:y_size
                        tic
                        %                     [ret] = WaitForAcquisition();
                        %                     CheckWarning(ret);

                        for i=1:x_size
                            if k==1
                                x = x_0-(i-1)*step_map; %positions in microns
                                y = y_0+(j-1)*step_map;
                                LesPos(j,i,:) = [x y]; %positions x y in microns
                            else
                                x = x_0-(j-1)*step_map; %positions in microns
                                y = y_0+(i-1)*step_map;
                            end

                            %if rem(count,2)==0 %mapping as a snake

                            %                     else
                            %                         x = x_0-(x_size-1)*step_map+(i-1)*step_map;
                            %                         y = y_0+(j-1)*step_map;
                            %
                            %                     end
                            send_DAQ_output(app, micron2volt(app, x), micron2volt(app,y), 0);
                            % check pause/stop
                            stopexp = check_if_pause_or_stop(app);
                            if stopexp
                                break
                            end

                        end
                        toc
                        %
                        %
                        [ret, imageData] = GetMostRecentImage(xpixels);
                        CheckWarning(ret);
                        plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, imageData-bkg);
                        %                     %
                        assignin('base', 'imdata',imageData)

                        % Z = sum(imageData-bkg);
                        Z = max(imageData-bkg);
                        Map_max(k,i)
                        assignin('base', 'max_z',Map_max)
                        if k==1 & Z>threshold
                            LaMap_1(j)=1;
                        end
                        if k==2 & Z>threshold
                            LaMap_2(j)=1;
                        end


                        %                     switch app.MapShowQuantityDropDown.Value
                        %                         case 'Amplitude'
                        %                             [Z, ~] = max(LaMap,[],3);
                        %                         case 'Intensity'
                        %
                        %                         case 'Wavelength'
                        %                             [~, id] = max(LaMap,[],3);
                        %                             Z = app.InstrInfo.Spectrometer.calib(id);
                        %                     end

                        if stopexp
                            break
                        end
                    end
                end


                [ret]=AbortAcquisition;
                CheckWarning(ret);
                %
                for j=1:y_size
                    for i=1:x_size
                        if LaMap_1(i)==1 & LaMap_2(j)==1
                            Map_end(i,j)=1;
                        end
                    end
                end
                %   Map_end=LaMap_1*LaMap_2;

                assignin('base', 'Lamap1',LaMap_1)
                assignin('base', 'Lamap2',LaMap_2)
                assignin('base', 'mapend',Map_end)
                imagesc(app.map_image, 1:x_size,1:y_size, Map_end);
                axis(app.map_image,[-Inf Inf -Inf Inf])

                c=colorbar(app.map_image,'SouthOutside');
                c.Color='w';

                toggle_shutter(app,'close',0)
                set_app_state(app,'idle')


            end

            function results = save_data(app,meas)
                % ok

                % a nettoyer et commenter mais ca fonctionne

                thisdotnum = app.NCSpinner.Value;


                %%%%% Recover data to save
                switch meas
                    case 'Spectrum'
                        switch app.conversionSwitch.Value
                            case 'nm'
                                X = app.UIAxes.Children(1).XData;
                            case 'eV'
                                X = app.hc./app.UIAxes.Children(1).XData;
                        end
                        Y = app.UIAxes.Children(1).YData;
                        Z = app.UIAxes.Children(1).ZData;

                        if ~isempty(Z)  % 2D image
                            ladata = Z;
                        else            % 1D spectrum
                            ladata = [app.hc./X' double(Y)'];
                        end

                        thismeastype = "spectrum";

                        L2Exc_deg = [];
                        L2Det_deg = [];
                    case 'XYMap'
                        thismeastype = 'XYMap';
                        ladata=app.LastMap.LaMap;
                        Suppl2D = app.LastMap.LesPos;
                        L2Exc_deg = [];
                        L2Det_deg = [];
                    case 'Polarisation'
                        thismeastype = "polar";
                        Z = app.map_image.Children.CData;
                        ladata=Z;

                        L2Exc_deg = [];
                        L2Det_deg = [];
                        disp('ici')
                        app.instrDropDown.Value
                        disp('la')
                        switch app.instrDropDown.Value
                            case 'ExcPol'
                                L2Exc_deg = [app.map_image.Children.YData app.moreInfo2save.Power_list];

                            case 'DetPol'
                                 L2Det_deg = [app.map_image.Children.YData];
                           
                            otherwise
                        end
                    case 'Trace'
                        thismeastype = "trace";
                        Z = app.map_image.Children.CData;
                        ladata=Z;
                        L2Exc_deg = [];
                        L2Det_deg = [];
                end

                % generate measurement number
                [allmeas, measnum, thismeasname] = generate_dataset_name(app,thisdotnum,thismeastype,meas);

                %measnum=2

                thismeas.Name = thismeasname;


                % general information on this measurement
                thismeas.Date = char(datetime("now",'Format','yy-MM-dd HH:mm:ss'));
                thismeas.Comment = [app.Comment.Value{:}];
                app.Comment.Value='';
                thismeas.T_K = app.TKEditField.Value;
                thismeas.EPow_uW = app.ExcPow.Value;
                thismeas.Time_ms = app.AcqTimeEditField.Value.*1e3;
                thismeas.WL_nm = app.InstrInfo.Spectrometer.calib;
                thismeas.Suppl = app.InstrInfo.CCD.bkg;
                switch thismeastype
                    case 'XYMap'
                        thismeas.Suppl2D = Suppl2D;
                        thismeas.xy_BSI2Map=app.moreInfo2save.xy_BSI2Map;
                        thismeas.BSI_image_file=app.moreInfo2save.BSI_image_file;
                    otherwise
                end
                thismeas.L2Exc_deg = L2Exc_deg;
                thismeas.L2Det_deg = L2Det_deg;
                thismeas.Att = [NaN; NaN; NaN];
                thismeas.FSM = [app.FSM_xREditField.Value app.FSM_yREditField.Value];
                thismeas.Marker = [app.MarkerUpEditField.Value app.MarkerDownEditField.Value];
                thismeas.Detector = app.DetectorDropDown.Value;
                thismeas.Grating = round(app.InstrInfo.Spectrometer.currGratingInfo(app.InstrInfo.Spectrometer.currGrating).lines) ;
                thismeas.ESource = app.SourceDropDown.Value;
                thismeas.EWL_nm = app.EWLnmEditField.Value;


                % general information on this sample
                thismeas.SamplePrep = app.SamplePrepDatePicker.Value;
                thismeas.SampleOf = app.SampleOfDropDown.Value;
                thismeas.SynthName = app.SynthNameEditField.Value;
                thismeas.SynthTemp = app.SynthTempEditField.Value;
                thismeas.SynthDilution = app.SynthDilutionEditField.Value;
                thismeas.SynthPolym = app.SynthPolymEditField.Value;
                thismeas.SynthPolymPerc = app.SynthPolymPercEditField.Value;
                thismeas.SynthSolv = app.SynthSolvEditField.Value;


                thismeas.Data = ladata;

                try
                    lastdatasetindex = length(allmeas.(meas));
                catch
                    lastdatasetindex=0;
                end
                allmeas.(meas)(lastdatasetindex+1) = thismeas;

                fullmatfilepath = [generate_path(app) + "\" + generate_filename(app) + ".mat"];
                save(fullmatfilepath, 'allmeas')

            end

            function thefilename = generate_filename(app)
                ladate = char(datetime("now",'Format','yy-MM-dd'));
                lasynthese = app.SynthNameEditField.Value;
                laconcentration = app.SynthDilutionEditField.Value;
                lesolvant = app.SynthSolvEditField.Value;
                lepolymere = app.SynthPolymEditField.Value;
                laconcentrationdepolymere = app.SynthPolymPercEditField.Value;
                MrkX = app.MarkerUpEditField.Value;
                MrkY = app.MarkerDownEditField.Value;
                thefilename = sprintf('%s_%s_%d%s%s%.1f_Marker%dx%d',ladate,lasynthese,laconcentration,lesolvant,lepolymere,laconcentrationdepolymere,MrkX,MrkY);
                if app.NCSpinner.Value < 1
                    app.CARODOTManipUIFigure.Name = ["CARODOTManip: "+thefilename];
                else
                    app.CARODOTManipUIFigure.Name = ["CARODOTManip: "+thefilename+" NC"+sprintf('%02.0f',app.NCSpinner.Value)];
                end
            end

            function newpath = generate_path(app)
                basepath = app.AppInfo.basepath_data;

                lannee = string(datetime("now",'Format','yyyy'));
                lanneeetlemois = string(datetime("now",'Format','yyyy-MM'));
                ladate = string(datetime("now",'Format','yyyy-MM-dd'));

                newpath = basepath + "\" + lannee + "\" + lanneeetlemois + "\" + ladate;
                if ~exist(newpath, 'dir')
                    mkdir(newpath);
                end
            end

            function [allmeas, currentindex, thismeasname] = generate_dataset_name(app,thisdotnum,thismeastype,meas)

                % get data file if already exists
                currentfile = [generate_path(app) + "\" + generate_filename(app) + ".mat"];

                if exist(currentfile,'file')==2 % load if exists
                    temp=load(currentfile);
                    allmeas=temp.allmeas;

                    if isfield(allmeas,meas)
                        % get all names

                        % compare to expected string to find all datasets of same
                        % meas on same NC
                        pat=sprintf('NC%02d_%s_(?<measnum>\\d+)',thisdotnum,thismeastype);

                        % get N the number of matching results.
                        %allmeas.(meas).Name


                        tmp=arrayfun(@(x) string(x.Name),allmeas.(meas),'UniformOutput',false);
                        nameslist = [tmp{:}];
                        %super sale
                        ghj=[regexp(nameslist,pat,'names')]
                        Ghj = [];
                        for kk=1:length(ghj)
                            rg=regexp(nameslist(kk),pat,'names');
                            if ~isempty(rg)
                                Ghj = [Ghj str2num(rg.measnum)];
                            end
                        end
                        Ghj
                        isempty(Ghj)

                        numberslist=max(Ghj)
                        % try
                        %                 numberslist = cellfun(@(x) str2num(x.measnum),ghj)
                        % catch
                        % try
                        %     numberslist=str2num(ghj.measnum)
                        % catch
                        %     numberslist=0;
                        % end
                        % end

                        % since numbering starts at 0, the next dataset to save
                        % should be numbered as (N)

                        currentindex=max(numberslist)+1;
                    else
                        currentindex=0;
                    end
                else
                    allmeas=struct;
                    currentindex=0;
                end
                thismeasname = sprintf('NC%02.0f_%s_%02.0f', thisdotnum,thismeastype, currentindex);
                add2Log(app,thismeasname)

            end


            function init_TWTBP1(app)
                % filter set n°1
                address = [0 1 2 3] % 0: linear 1-2-3: rotation stages
                ELL_TWTBP1= elliptec_driver(address,"COM5");

                app.InstrInfo.ELL_TWTBP1 = ELL_TWTBP1;

                for i=1:length(address)
                    pos=get_position(ELL_TWTBP1, address(i));

                    stepsize = 5;
                    set_jogstep(ELL_TWTBP1, address(i),stepsize);


                    %app.InstrInfo.ELL_TWTBP1.serialdev = visdedev;
                    %             home=[11 25 38]
                    %             for i=1:3
                    %                 app.InstrInfo.ELL_rotA(i).serialdev=visdedev;
                    %                 sprintf('%d',i)
                    %                 app.InstrInfo.ELL_rotA(i).device_adress=sprintf('%d',i);
                    %                 app.InstrInfo.ELL_rotA(i).type='RS'; %rotation mount i
                    %
                    %                 elldevice = app.InstrInfo.ELL_rotA(i);
                    %
                    %                 get_homeoffset(elldevice.serialdev,  elldevice.device_adress);
                    %
                    %                 assignin('base',"i",i)
                    %                 pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    %                 pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    %
                    %                 stepsize = 5;
                    %                 set_jogstep(elldevice.serialdev,  elldevice.device_adress, elldevice.type,stepsize);
                    %                 move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, home(i));

                    %             stepsize = get_jogstep(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    switch address(i)
                        case 1
                            app.ReadoutEditField.Value = pos;
                            app.StepEditField.Value = stepsize;
                        case 2
                            app.ReadoutEditField_2.Value = pos;
                            app.StepEditField_2.Value = stepsize;
                        case 3
                            app.ReadoutEditField_3.Value = pos;
                            app.StepEditField_3.Value = stepsize;
                        otherwise
                    end
                end


            end

            function master_init_meas(app)
                % code to initialise measurement parameters
                % and plots

                switch app.DetectorDropDown.Value
                    case 'CCD1D'
                        datasize=1;
                        readcode = getAndorReadCode(app);
                        acqcode = getAndorAcqCode(app);
                        setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, 0)
                        [ret]=SetShutter(1, 0, 0, 40);                 %   Open Shutter IN FULLY AUTO
                        CheckWarning(ret);

                        [ret]=SetSingleTrack(app.centralvalueSpinner.Value, app.heightSpinner.Value);
                        CheckWarning(ret);
                        [ret,xpixels, ~] = GetDetector;           %   Get the CCD size
                        CheckWarning(ret);
                        app.InstrInfo.Spectrometer.xpixels= xpixels;
                        %                     app.UIAxes.XLabel = 'Wavelength (nm)'; app.UIAxes.YLabel = 'Pixel intensity';
                        xlabel(app.UIAxes, 'Wavelength (nm)');
                        ylabel(app.UIAxes, 'Pixel intensity')
                    case 'CCD2D'
                        datasize=2;
                        h_bin=app.hbinSpinner.Value;
                        v_bin=app.vbinSpinner.Value;
                        h_start=app.hstartSpinner.Value;
                        h_end=app.hendSpinner.Value;
                        v_start=app.vstartSpinner.Value;
                        v_end=app.vendSpinner.Value;
                        %[ret,xpixels, ypixels]=GetDetector;           %   Get the CCD size
                        % CheckWarning(ret);
                        [ret]=SetImage(h_bin, v_bin, h_start, h_end, v_start, v_end); %   Set the image size
                        CheckWarning(ret);
                        % ypixels_ROI = 50; ypixels_y0 = 101;
                        xpixels=h_end-h_start+1;
                        ypixels=v_end-v_start+1;
                        %                     app.UIAxes.XLabel = 'Wavelength (nm)'; app.UIAxes.YLabel = 'Pixel';
                        xlabel(app.UIAxes, 'Wavelength (nm)');
                        ylabel(app.UIAxes, 'Pixel')
                    case 'APDA'
                        datasize=0;
                        %                     app.UIAxes.YLabel = 'Counts';
                        ylabel(app.UIAxes, 'Counts');
                    case 'APDB'
                        datasize=0;
                        %                     app.UIAxes.YLabel = 'Counts';
                        ylabel(app.UIAxes, 'Counts');
                    case 'APDA+B'
                        datasize=0;
                        %                     app.UIAxes.YLabel = 'Counts';
                        ylabel(app.UIAxes, 'Counts');
                    case 'PD1'
                        datasize=0;
                        %                     app.UIAxes.YLabel = 'Power';
                        ylabel(app.UIAxes, 'Power');
                    case 'BSI'
                        datasize=2;
                        %                     app.UIAxes.XLabel = 'Pixel'; app.UIAxes.YLabel = 'Pixel';
                        xlabel(app.UIAxes, 'Pixel');
                        ylabel(app.UIAxes, 'Pixel')
                end

                if datasize==0
                    switch app.instrDropDown.Value
                        case 'TWTBPFilter A'
                        case {'BP1A', 'BP2A', 'CPA'}
                            xlabel(app.UIAxes, 'Angle (deg)');
                            %                         app.UIAxes.XLabel = 'Angle (deg)';
                        case 'ExcPol'
                            app.thismeas='Polarisation';
                            xlabel(app.UIAxes, 'Angle (deg)');
                            %                         app.UIAxes.XLabel = 'Angle (deg)';
                        case 'DetPol'
                            app.thismeas='Polarisation';
                            xlabel(app.UIAxes, 'Angle (deg)');
                            %                         app.UIAxes.XLabel = 'Angle (deg)';
                        case {'Spectrometer','CCD iDus'}
                            xlabel(app.UIAxes, 'Center wavelength (nm)');
                            %                         app.UIAxes.XLabel = 'Center wavelength (nm)';
                        case 'LED'
                            xlabel(app.UIAxes, 'LED voltage (V)');
                            %                         app.UIAxes.XLabel = 'LED voltage (V)';
                        otherwise
                    end
                else
                    switch app.instrDropDown.Value
                        case 'TWTBPFilter A'
                        case {'BP1A', 'BP2A', 'CPA'}
                            %                         app.map_image.YLabel = 'Angle (deg)';
                            ylabel(app.map_image, 'Angle (deg)');
                            %ylabel(app.UIAxes, 'Pixel')
                        case 'ExcPol'
                            app.thismeas='Polarisation';
                            app.map_image.YLabel.String = 'Angle (deg)';
                        case 'DetPol'
                            app.thismeas='Polarisation';
                            app.map_image.YLabel.String  = 'Angle (deg)';
                        case {'Spectrometer','CCD iDus'}
                            app.map_image.YLabel.String = 'Center wavelength (nm)';
                        case 'LED'
                            app.map_image.YLabel.String = 'LED voltage (V)';
                        otherwise
                    end
                end
            end

            function corr_x = master_move(app, thisx)
                corr_x = NaN;
                switch app.instrDropDown.Value
                    case 'TWTBPFilter A'
                        % fonction pas evidente pas encore faite
                    case 'BP1A'
                        %                     elldevice = app.InstrInfo.ELL_rotA(1);
                        %                     pos=move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, thisx);
                        elldevice=app.InstrInfo.ELL_TWTBP1;
                        pos=set_position(elldevice, 1, thisx);
                        app.ReadoutEditField.Value=pos;
                    case 'BP2A'
                        %                     elldevice = app.InstrInfo.ELL_rotA(2);
                        %                     pos=move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, thisx);
                        elldevice=app.InstrInfo.ELL_TWTBP1;
                        pos=set_position(elldevice, 2, thisx);
                        app.ReadoutEditField_2.Value=pos;
                    case 'CPA'
                        %                     elldevice = app.InstrInfo.ELL_rotA(3);
                        %                     pos=move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, thisx);
                        elldevice=app.InstrInfo.ELL_TWTBP1;
                        pos=set_position(elldevice, 3, thisx);
                        app.ReadoutEditField_3.Value=pos;
                    case 'ExcPol'
                        elldevice = app.InstrInfo.ELL_shutterpol;
                        pos=set_position(elldevice, 2, thisx);
                        %                     pos=move_absolute(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type, thisx);
                        app.PolarExcReadoutEditField.Value=pos;
                        corr_x = getPDpower(app);
                    case 'DetPol'
                        %                     elldevice = app.InstrInfo.ELL_detpol;
                        %                     pos=move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, thisx);
                        elldevice = app.InstrInfo.ELL_detpol;
                        pos=set_position(elldevice, 0, thisx);
                        app.PolarDetReadoutEditField.Value=pos;
                    case 'Spectrometer'
                        setWavelength(app,thisx);
                    case 'LED'
                    otherwise
                end
            end

            function setWavelength(app, value)
                app.WavelengthnmEditField.Enable=false;

                [ret] = ATSpectrographSetWavelength(0,value);
                ATSpectrographCheckWarning(ret);

                [ret, app.WavelengthnmEditField.Value] = ATSpectrographGetWavelength(0);
                ATSpectrographCheckWarning(ret);


                [ret, app.InstrInfo.Spectrometer.calib] = ATSpectrographGetCalibration(0,app.InstrInfo.CCD.xpixels);
                ATSpectrographCheckWarning(ret);
                app.WavelengthnmEditField.Enable=true;

            end

            function [output0D, output1D, output2D, wavelength, thisacq] = master_acquire(app)

                output0D=NaN;
                output1D=NaN;
                output2D=NaN;
                wavelength=NaN;

                switch app.DetectorDropDown.Value
                    case 'CCD1D'
                        [ret] = StartAcquisition();
                        CheckWarning(ret);
                        tic
                        [ret] = WaitForAcquisition();
                        CheckWarning(ret);

                        [ret, imageData] = GetMostRecentImage(app.InstrInfo.CCD.xpixels);
                        CheckWarning(ret);
                        toc

                        [ret]=AbortAcquisition;
                        CheckWarning(ret);

                        plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, imageData)
                        ylabel(app.UIAxes,'Pixel intensity')
                        colorbar(app.UIAxes,'off')
                        app.LargeCounterField.Value= sum(imageData);

                        %                     drawnow
                        wavelength=app.InstrInfo.Spectrometer.calib;
                        output1D = imageData;
                        thisacq=output1D;
                    case 'CCD2D'
                        [ret] = StartAcquisition();
                        CheckWarning(ret);

                        [ret] = WaitForAcquisition();
                        CheckWarning(ret);

                        [ret, imageData] = GetMostRecentImage(xpixels.*ypixels);
                        CheckWarning(ret);

                        [ret]=AbortAcquisition;
                        CheckWarning(ret);

                        myImageData = reshape(imageData, xpixels, ypixels);

                        x_axis = app.InstrInfo.Spectrometer.calib;
                        p=pcolor(app.UIAxes, x_axis,1:ypixels, flipud(myImageData'));
                        p.EdgeColor='none';
                        c=colorbar(app.UIAxes); c.Color='w';
                        c.Title.String = "Pixel intensity";
                        c.Title.Color=[1 1 1];
                        [m, ~] = maxk(myImageData(:),2);
                        caxis(app.UIAxes,[min(myImageData(:)) m(2)])
                        xlim(app.UIAxes,[-Inf Inf])
                        ylim(app.UIAxes,[-Inf Inf])

                        wavelength=app.InstrInfo.Spectrometer.calib;
                        output2D = imageData;
                        thisacq=output2D;
                    case 'APDA'
                    case 'APDB'
                    case 'APDA+B'
                    case 'PD1'

                        output0D = getPDpower_avg(app,app.AcqTimeEditField.Value);
                        thisacq=output0D;
                    case 'BSI'
                end


                if size(thisacq,1)==size(thisacq,2)==1 % 0D acq
                    % app.UIAxes.Children.YData = [app.UIAxes.Children.YData thisacq];
                else if size(thisacq,1)==1 & size(thisacq,2)~=1 % 1D acq

                else % 2D acq

                end

                end


            end

            function focus_on_main_graph(app)
                % make sure focus is on the main graph
                if ~isequal(app.MainTab.SelectedTab,app.MainGraphTab)
                    app.MainTab.SelectedTab = app.MainGraphTab;
                end
            end

            function power = getPDpower_avg(app,timeavg)
                allres = [];
                idx=0;
                tic
                while (toc < timeavg) && (idx<1e2)
                    idx=idx+1;
                    thisres = getPDpower(app);
                    allres = [allres thisres];
                end
                power = mean(allres);
            end


            function power = getPDpower(app)
                % max rate is 10 ps
                % so reading update min of 0.1
                app.InstrInfo.PMeter.updateReading(0.1); % Update the power reading(with interal period of 0.5s)
                power=app.InstrInfo.PMeter.meterPowerReading;%,test_meter.meterPowerUnit;% in Watts
            end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AppInfo.basepath_app = 'C:\Users\tharrault\Documents\CARODOTManip';
            app.AppInfo.basepath_data = 'C:\Users\tharrault\Documents\CARODAT';
            generate_path(app); generate_filename(app);
            cd(app.AppInfo.basepath_app)

            if ~isfield(app.AppInfo, "restart_mode")
                app.AppInfo.restart_mode = "false";
            end

            set_app_state(app,'busy')

            % init physics
            app.hc = 1239.84198;

            % change focus to settings tab
            app.MainTab.SelectedTab = app.SettingsTab;

            % startup parameters
            % go to correct folder and load user parameters
            GraphicalInterfaceStartup(app,'PuOr') % cust

            % Initialise ?

            app.init_answer = questdlg('Initialize all devices?');
            switch app.init_answer
                case 'No'
                    % SANDBOX
                    add2Log(app,'No devices initialised')
                case 'Cancel'
                    delete(app)
                otherwise
                    initAllDevices(app); % initialise all selected instruments
            end
            set_app_state(app,'idle')

        end

        % Button pushed function: DButton, HButton, LButton, RButton, 
        % UButton
        function UButtonPushed(app, event)
            whichbutton = event.Source.Text;
            x_value=micron2volt(app,app.FSM_xREditField.Value);
            y_value=micron2volt(app, app.FSM_yREditField.Value);
            step=micron2volt(app,app.FSMStepEditField.Value);
            switch whichbutton
                case 'U'
                    thisdir = [0 -1];
                case 'D'
                    thisdir = [0 1];
                case 'L'
                    thisdir = [1 0];
                case 'R'
                    thisdir = [-1 0];
                case 'H'
                    thisdir = [0 0];
            end

            nextpos = [ x_value + thisdir(1).*step ...
                y_value + thisdir(2).*step];

            switch whichbutton
                case 'H'
                    nextpos = [micron2volt(app, app.FSM_x0EditField.Value) micron2volt(app, app.FSM_y0EditField.Value)];
            end
            send_DAQ_output(app, nextpos(1), nextpos(2), app.LEDKnob.Value)
        end

        % Value changed function: LEDKnob
        function LEDKnobValueChanged(app, event)
            V_LED = app.LEDKnob.Value;
            V_x=micron2volt(app, app.FSM_xREditField.Value);
            V_y=micron2volt(app, app.FSM_yREditField.Value);
            send_DAQ_output(app,V_x,V_y,V_LED)
        end

        % Value changed function: FSM_xTEditField
        function FSM_xTEditFieldValueChanged(app, event)
            V_x_new = micron2volt(app, app.FSM_xTEditField.Value);
            V_y = micron2volt(app, app.FSM_yREditField.Value);
            V_LED = app.LEDKnob.Value;
            send_DAQ_output(app,V_x_new,V_y,V_LED)
        end

        % Value changed function: FSM_yTEditField
        function FSM_yTEditFieldValueChanged(app, event)
            V_y_new = micron2volt(app, app.FSM_yTEditField.Value);
            V_x = micron2volt(app, app.FSM_xREditField.Value);
            V_LED = app.LEDKnob.Value;
            send_DAQ_output(app,V_x,V_y_new,V_LED)
        end

        % Callback function: AcquirePicturePushTool, Button
        function ButtonPushed(app, event)
            % turn on led
            V_LED = 3;
            V_y= micron2volt(app, app.FSM_yREditField.Value);
            V_x = micron2volt(app, app.FSM_xREditField.Value);
            send_DAQ_output(app,V_x,V_y,V_LED)


            % move BS into optical path
            position=42; %max position 60 mm


            %%%%%%
            % Suggestion, remplacer
            % move_absolute(app.InstrInfo.ELL_linear.serialdev,  app.InstrInfo.ELL_linear.device_adress, app.InstrInfo.ELL_linear.type, position)
            % par ce qui suit pour clarifier le code
            elldevice = app.InstrInfo.ELL_linear;
            move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position)
            % voir meme changer move_absolute directement pour input
            % move_absolute(elldevice, position)
            % puisque serialdev, device_adress et type sont dans
            % elldevice=app.InstrInfo.ELL_linear
            %%%%%%

            pause(1)

            acquire_image(app)

            % move BS out of optical path
            %%%%%
            % pareil pour go_home => go_home(elldevice)
            go_home( app.InstrInfo.ELL_linear.serialdev,  app.InstrInfo.ELL_linear.device_adress, app.InstrInfo.ELL_linear.type)
            %%%%%


            % turn off led
            V_LED=0;
            send_DAQ_output(app,V_x,V_y,V_LED)
        end

        % Button pushed function: GetPower
        function GetPowerPushed(app, event)

            power = getPDpower(app);

            app.ExcPow.Value = power.*1e6;
            app.ExcPow.Value=power;% Disconnect and release

        end

        % Value changed function: ExcPow
        function ExcPowValueChanged(app, event)
            value = app.ExcPow.Value;

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.AppInfo.restart_mode = "false";
            closeAllDevices(app);
            app.delete % Shut down the entire app
        end

        % Close request function: CARODOTManipUIFigure
        function CARODOTManipUIFigureCloseRequest(app, event)
            ExitMenuSelected(app, event)
            delete(app)
        end

        % Clicked callback: shutter_tool
        function shutter_toolClicked(app, event)
            elldevice=app.InstrInfo.ELL_shutterpol;
            pos=get_position(elldevice, 1);
            switch pos
                case 0 %shutter is closed
                    toggle_shutter(app, 'open', 0.05);
                case 31 %shutter is opened
                    toggle_shutter(app, 'close', 0.05);
                otherwise
            end
        end

        % Callback function: SelectedInstruments
        function SelectedInstrumentsCheckedNodesChanged(app, event)
            checkedNodes = app.SelectedInstruments.CheckedNodes;
        end

        % Menu selected function: ExportapptoworkspaceMenu
        function ExportapptoworkspaceMenuSelected(app, event)
            assignin('base','myapp',app)
            assignin('base','InstrInfo', app.InstrInfo)
            assignin('base','LastMap', app.LastMap)
        end

        % Clicked callback: AcquireCustMoviePushTool
        function AcquireCustMoviePushToolClicked(app, event)
            % turn on led
            V_LED = 3;
            app.LEDKnob.Value=V_LED;
            V_x=app.FSM_xREditField.Value;
            V_y=app.FSM_yREditField.Value;
            send_DAQ_output(app,V_x,V_y,V_LED)


            % move BS into optical path
            position=45; %max position 60 mm


            %%%%%%
            % Suggestion, remplacer
            % move_absolute(app.InstrInfo.ELL_linear.serialdev,  app.InstrInfo.ELL_linear.device_adress, app.InstrInfo.ELL_linear.type, position)
            % par ce qui suit pour clarifier le code
            elldevice = app.InstrInfo.ELL_linear;
            move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position)
            % voir meme changer move_absolute directement pour input
            % move_absolute(elldevice, position)
            % puisque serialdev, device_adress et type sont dans
            % elldevice=app.InstrInfo.ELL_linear
            %%%%%%

            pause(1)

            app.CustMovieStopButton.Visible = true;
            app.CustMovieStopButton.Enable = true;
            %
            delete(gcp('nocreate'));
            parpool('local', 1);
            app.InstrInfo.Parallel.p = parallel.pool.PollableDataQueue;
            %             app.InstrInfo.Parallel.test=23;

            thepool = app.InstrInfo.Parallel.p;
            thecaminfo = app.InstrInfo.CamInfo;
            app.InstrInfo.Parallel.f=parfeval(@acq__SoftwareTrigger,0,thepool,thecaminfo);
            wait(app.InstrInfo.Parallel.f);
            %             cancel(f);
            poll(app.InstrInfo.Parallel.p)

            % move BS out of optical path
            %%%%%
            % pareil pour go_home => go_home(elldevice)
            go_home( app.InstrInfo.ELL_linear.serialdev,  app.InstrInfo.ELL_linear.device_adress, app.InstrInfo.ELL_linear.type)
            %%%%%


            % turn off led
            V_LED=0;
            send_DAQ_output(app,V_x,V_y,V_LED)
            app.CustMovieStopButton.Value=false;
            %             function acquire_image_continuous(res,camim,D)
            % %             kk=0;
            % %             while  kk<100 %% & app.CustMovieStopButton.Value==false
            % %                app.ppwd = pwd;
            % %             [~, app.ppwd] = go2_Camfolder();
            %             myImage = acq__SoftwareTrigger(res,D); % take a pic w/ ThorCam
            %             imagesc(camim, myImage)%.ImageSource= "latestimage.png";
            %             xlim(camim,[1 1440]); ylim(camim,[1 1080]);
            % %             drawnow
            % %             assignin('base','x',app.CameraImage)
            % %             goback2_folder(app.ppwd);
            %                 %pause(0.2)
            % %                 kk=kk+1;
            % %             end
            %
            %         end
            %         function goback2_folder(app)
            %             cd(app.ppwd)
            %         end
            %
            %         function [nwd, ppwd] = go2_Camfolder(app)
            %             ppwd = pwd;
            %             nwd = ['C:\Users\tharrault\Documents\CARODOTManip\Interfacing_tests\Scientific Camera Interfaces\MATLAB\'];
            %             cd(nwd)
            %
            %             function acq__SoftwareTrigger2(D,res)
            %                 result=res+1
            %                 send(D,result)
        end


        function acq__SoftwareTrigger(D,res)
            nwd = [app.AppInfo.basepath_app + "\Interfacing_tests\Scientific Camera Interfaces\MATLAB\"];
            cd(nwd)
            tlCamera = res.tlCamera;
            tlCameraSDK = res.tlCameraSDK;
            serialNumbers = res.serialNumbers;
            isColorCamera = res.isColorCamera;
            maxPixelValue = res.maxPixelValue;
            demosaicker = res.demosaicker;
            bitDepth = res.bitDepth;
            standardRGBColorProcessor = res.standardRGBColorProcessor;
            colorFilterArrayPhase = res.colorFilterArrayPhase;
            colorProcessorSDK = res.colorProcessorSDK;
            % %
            %
            numberOfFramesToAcquire = 1;
            for iloop = 1:numberOfFramesToAcquire
                % Each call to Start sends a software trigger.
                tlCamera.IssueSoftwareTrigger;

                % Wait for image buffer to be filled to prevent sending too many
                % software triggers.
                while (tlCamera.NumberOfQueuedFrames == 0)
                    pause(0.01);
                end

                % If data processing in Matlab falls behind camera image
                % acquisition, the FIFO image frame buffer could be filled up,
                % which would result in missed frames.
                if (tlCamera.NumberOfQueuedFrames > 1)
                    disp(['Data processing falling behind acquisition. ' num2str(tlCamera.NumberOfQueuedFrames) ' remains']);
                end
                disp('bef acq')
                % Get the pending image frame.
                imageFrame = tlCamera.GetPendingFrameOrNull;
                if ~isempty(imageFrame)
                    % For color images, the image data is in BGR format.
                    imageData = imageFrame.ImageData.ImageData_monoOrBGR;

                    disp(['Image frame number: ' num2str(imageFrame.FrameNumber)]);

                    % TODO: custom image processing code goes here
                    imageHeight = imageFrame.ImageData.Height_pixels;
                    imageWidth = imageFrame.ImageData.Width_pixels;
                    if (isColorCamera)
                        % Allocate memory for demosaicking output.
                        demosaickedImageData = NET.createArray('System.UInt16',imageHeight * imageWidth * 3);
                        colorFormat = Thorlabs.TSI.ColorInterfaces.ColorFormat.BGRPixel;
                        % Demosaic the Bayer patterned image from the camera.
                        demosaicker.Demosaic(imageWidth, imageHeight, int32(0), int32(0), colorFilterArrayPhase,...
                            colorFormat, Thorlabs.TSI.ColorInterfaces.ColorSensorType.Bayer,...
                            bitDepth, imageData, demosaickedImageData);

                        % Allocate memory for color processed image.
                        processedImageData = NET.createArray('System.UInt16',imageHeight * imageWidth * 3);

                        % Use the color processor to perform color transform.
                        standardRGBColorProcessor.Transform48To48(demosaickedImageData, colorFormat,...
                            uint16(0), uint16(maxPixelValue), uint16(0), uint16(maxPixelValue),...
                            uint16(0), uint16(maxPixelValue), int32(0), int32(0), int32(0), processedImageData, colorFormat);

                        % Display the color image
                        imageColor = reshape(uint16(processedImageData), [3, imageWidth, imageHeight]);
                        imageColor = double(permute(imageColor,[3 2 1]));
                        imageColor = flip(imageColor,3);    % Change from BGR to RGB
                        %figure(1),image(imageColor/maxPixelValue)%, %colorbar
                        %set(gca,'visible','off')

                    else
                        imageData2D = reshape(uint16(imageData), [imageWidth, imageHeight]);
                        %figure(1),imagesc(imageData2D'), colormap(gray), colorbar
                    end
                end
                % Release the image frame
                delete(imageFrame);
                %         drawnow;
            end
            myNormalisedImage = imageColor/maxPixelValue;
            send(D,myNormalisedImage)
            %imwrite(myNormalisedImage,'latestimage.png')
            % Stop software triggered image acquisition
            %     disp('Stopping software triggered image acquisition.')

        end

        % Clicked callback: FastAcquire
        function FastAcquireClicked(app, event)
            %%% For now, fast acquire only triggers single acquisitions with CCD1D or CCD2D
            %%% depending on the selected Detector
            % if another detector is selected, the acquisition defaults to a CCD1D

            if ~strcmp(app.DetectorDropDown.Value,"CCD1D")
                if ~strcmp(app.DetectorDropDown.Value,"CCD2D")
                    app.DetectorDropDown.Value="CCD1D";
                end
            end
            % initialise measurement
            set_app_state(app,'busy')

            switch app.DetectorDropDown.Value
                case 'CCD1D'
                    %      set acq params
                    app.AndorReadoutModeDropDown.Value = "Single-track";
                    readcode = 3;
                    %                     app.AndorAcqModeDropDown.Value = "Single scan";


                    %                     setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, 0)
                    [ret]=SetShutter(1, 0, 0, 0);                 %   Open Shutter IN FULLY AUTO
                    CheckWarning(ret);

                    % set single track ROI
                    [ret]=SetSingleTrack(app.centralvalueSpinner.Value, app.heightSpinner.Value);
                    CheckWarning(ret);
                    [ret,xpixels, ~] = GetDetector;           %   Get the CCD size
                    CheckWarning(ret);
                    app.InstrInfo.Spectrometer.xpixels= xpixels;

                    acqcode = 1; %Single scan for background if requested
                    trigmode=0;
                    setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, trigmode) %background acquuisition parameters
                    app.InstrInfo.CCD.bkg = acquire_background(app,app.backgroundCheckBox.Value);

                    switch app.AndorAcqModeDropDown.Value
                        case "Single scan"
                            app.thismeas = 'Spectrum';
                            [ret] = StartAcquisition();
                            CheckWarning(ret);

                            [ret] = WaitForAcquisition();
                            CheckWarning(ret);


                            [ret, imageData] = GetMostRecentImage(xpixels);
                            CheckWarning(ret);


                            plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, imageData-app.InstrInfo.CCD.bkg )
                            switch_eV_nm(app)
                            drawnow
                            ylabel(app.UIAxes,'Pixel intensity')
                            colorbar(app.UIAxes,'off')
                            app.LargeCounterField.Value= sum(imageData-app.InstrInfo.CCD.bkg);

                        case "Kinetic series"
                            app.thismeas = 'Trace';
                            frameCount = app.AccumSpinner.Value; %number of spectra
                            acqcode = 3; %kinetic series mode
                            tacq = app.AcqTimeEditField.Value;
                            setAcqParams(app,acqcode, tacq, readcode, trigmode)

                            [ret]=SetNumberKinetics(frameCount);
                            CheckWarning(ret);
                            [ret] = StartAcquisition();
                            CheckWarning(ret);

                            LaTrace = NaN(frameCount, xpixels);
                            YAxis_2D =  [0:tacq:(frameCount-1)*tacq];

                            currentSeries = 1;

                            im = imagesc(app.map_image, app.InstrInfo.Spectrometer.calib, YAxis_2D, LaTrace);
                            pl = plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, LaTrace(1,:));
                            switch_eV_nm(app)


                            while(currentSeries <= frameCount)

                                [ret] = WaitForAcquisition();
                                CheckWarning(ret);
                                [ret, imageData] = GetMostRecentImage(xpixels);
                                CheckWarning(ret);
                                pl.YData = imageData-app.InstrInfo.CCD.bkg;
                                % plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, );
                                app.LargeCounterField.Value= sum(imageData-app.InstrInfo.CCD.bkg);
                                LaTrace(currentSeries, :) = imageData-app.InstrInfo.CCD.bkg;
                                im.CData = LaTrace;
                                % imagesc(app.map_image, app.InstrInfo.Spectrometer.calib,YAxis_2D, LaTrace);
                                axis(app.map_image,[-Inf Inf -Inf Inf])
                                c=colorbar(app.map_image,'SouthOutside');
                                c.Color='w';
                                currentSeries=currentSeries+1;
                                drawnow
                            end
                            app.LastMap.Trace=LaTrace;
                            %                                                          app.LastMap.yTime=yTime;

                    end
                case 'CCD2D'
                    app.thismeas = 'Spectrum';
                    % set acq params
                    app.AndorReadoutModeDropDown.Value = "Image";
                    readcode = 4;
                    app.AndorAcqModeDropDown.Value = "Single scan";
                    acqcode = 1; %Single scan
                    setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, 0)
                    [ret]=SetShutter(1, 0, 0, 40);                 %   Open Shutter IN FULLY AUTO
                    CheckWarning(ret);

                    % set image ROI
                    h_bin=app.hbinSpinner.Value;
                    v_bin=app.vbinSpinner.Value;
                    h_start=app.hstartSpinner.Value;
                    h_end=app.hendSpinner.Value;
                    v_start=app.vstartSpinner.Value;
                    v_end=app.vendSpinner.Value;

                    [ret]=SetImage(h_bin, v_bin, h_start, h_end, v_start, v_end); %   Set the image size
                    CheckWarning(ret);
                    % ypixels_ROI = 50; ypixels_y0 = 101;
                    xpixels=h_end-h_start+1;
                    ypixels=v_end-v_start+1;
                    [ret,xpixels, ypixels]=GetDetector;           %   Get the CCD size
                    CheckWarning(ret);

                    app.InstrInfo.Spectrometer.ypixels= ypixels;
                    app.InstrInfo.Spectrometer.xpixels= xpixels;


                    app.InstrInfo.CCD.bkg = acquire_background(app,app.backgroundCheckBox.Value);


                    [ret] = StartAcquisition();
                    CheckWarning(ret);

                    [ret] = WaitForAcquisition();
                    CheckWarning(ret);

                    [ret, imageData] = GetMostRecentImage(xpixels.*ypixels);
                    CheckWarning(ret);

                    myImageData = reshape(imageData, xpixels, ypixels);

                    x_axis = app.InstrInfo.Spectrometer.calib;
                    %x_axis = 1:length(app.InstrInfo.Spectrometer.calib);
                    %                                 myBkg = reshape(app.InstrInfo.CCD.bkg, xpixels, ypixels);
                    % if app.backgroundCheckBox.Value==true
                    %     mybkg=reshape(app.InstrInfo.CCD.bkg,app.InstrInfo.Spectrometer.xpixels,app.InstrInfo.Spectrometer.ypixels);
                    %       p=pcolor(app.UIAxes, x_axis,1:ypixels, myImageData'-mybkg');
                    %  else
                    p=pcolor(app.UIAxes, x_axis,1:ypixels, myImageData');
                    switch_eV_nm(app)

                    %   end
                    p.EdgeColor='none';
                    c=colorbar(app.UIAxes); c.Color='w';
                    c.Title.String = "Pixel intensity";
                    c.Title.Color=[1 1 1];
                    [m, ~] = maxk(myImageData(:),2);
                    [min(myImageData(:)) m(2)]
                    caxis(app.UIAxes,[min(myImageData(:)) m(2)])
                    %                     xlim(app.UIAxes,[-Inf Inf])
                    %                     ylim(app.UIAxes,[-Inf Inf])
                    ylabel(app.UIAxes,'Pixel')
                otherwise
            end

            xlabel(app.UIAxes,'Wavelength (nm)')

            [ret]=AbortAcquisition;
            CheckWarning(ret);

            focus_on_main_graph(app)

            toggle_shutter(app,'close', 0);
            % save if autosave
            if app.AutosaveToggleTool.State%app.AutosaveCheckBox.Value
                save_data(app,app.thismeas)
            end
            set_app_state(app,'idle')

        end

        % Clicked callback: NewMoviePushTool
        function NewMoviePushToolClicked(app, event)
            % turn on led
            V_LED = 3;
            V_y= micron2volt(app, app.FSM_yREditField.Value);
            V_x = micron2volt(app, app.FSM_xREditField.Value);
            send_DAQ_output(app,V_x,V_y,V_LED)


            % move BS into optical path
            position=47; %max position 60 mm


            %%%%%%
            % Suggestion, remplacer
            % move_absolute(app.InstrInfo.ELL_linear.serialdev,  app.InstrInfo.ELL_linear.device_adress, app.InstrInfo.ELL_linear.type, position)
            % par ce qui suit pour clarifier le code
            elldevice = app.InstrInfo.ELL_linear;
            move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position)
            % voir meme changer move_absolute directement pour input
            % move_absolute(elldevice, position)
            % puisque serialdev, device_adress et type sont dans
            % elldevice=app.InstrInfo.ELL_linear
            %%%%%%

            pause(1)
            try

                % acquire image with Zelux camera
                app.ppwd = pwd;
                [~, app.ppwd] = go2Camfolder();

                acquire_Camera(app); %%%%%% NEW
                %imagesc(app.CameraImage, myImage)%.ImageSource= "latestimage.png";
                %             xlim(app.CameraImage,[1 1440]); ylim(app.CameraImage,[1 1080]);
                %             drawnow

                %             assignin('base','x',app.CameraImage)
                goback2folder(app.ppwd);

                % move BS out of optical path
                %%%%%
                % pareil pour go_home => go_home(elldevice)
                go_home( app.InstrInfo.ELL_linear.serialdev,  app.InstrInfo.ELL_linear.device_adress, app.InstrInfo.ELL_linear.type)
                %%%%%
                %pause(0.1)
                %imagesc(app.CameraImage,zeros(1080,1440)+eye(1080,1440))
                %             app.CustMovieStopButton.Value=0;
                % turn off led
                V_LED=0;
                send_DAQ_output(app,V_x,V_y,V_LED);
            catch
            end
        end

        % Button pushed function: VisusliderONButton
        function VisusliderONButtonPushed(app, event)
           

            position=41;
            elldevice = app.InstrInfo.ELL_linear;
            move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position)


        end

        % Value changed function: InputportDropDown
        function InputportDropDownValueChanged(app, event)
            set_app_state(app,'busy')

            new_input = app.InputportDropDown.Value;
            switch new_input
                case 'Direct'
                    port=0;
                case 'Side'
                    port=1;
            end
            [ret] = ATSpectrographSetFlipperMirror(0,1,port);
            ATSpectrographCheckError(ret);


            BkgValueChanged(app,event)
            set_app_state(app,'idle')

        end

        % Value changed function: OutputportDropDown
        function OutputportDropDownValueChanged(app, event)
            set_app_state(app,'busy')

            value = app.OutputportDropDown.Value;
            switch value
                case 'CCD'
                    port=0;
                case 'Free space'
                    port=1;
            end
            [ret] = ATSpectrographSetFlipperMirror(0,2,port);
            ATSpectrographCheckError(ret);
            set_app_state(app,'idle')

        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            set_app_state(app,'busy')

            value = app.WavelengthnmEditField.Value;
            setWavelength(app,value);

            BkgValueChanged(app,event)

            set_app_state(app,'idle')

        end

        % Value changed function: CustMovieStopButton
        function CustMovieStopButtonValueChanged(app, event)
            app.CustMovieStopButton.Value=1;

        end

        % Clicked callback: ContinuousAcqPushTool
        function ContinuousAcqPushToolClicked(app, event)
            readcode = getAndorReadCode(app);
            acqcode = 5;
            setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, 0)

            [ret] = SetKineticCycleTime(0);
            CheckWarning(ret);

            [ret]=SetShutter(1, 0, 0, 0);                 %   Open Shutter IN FULLY AUTO
            CheckWarning(ret);

            [ret] = StartAcquisition();
            CheckWarning(ret);

            [ret] = WaitForAcquisition();
            CheckWarning(ret);

            [ret, imageData] = GetMostRecentImage(app.InstrInfo.CCD.xpixels);
            CheckWarning(ret);
            plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, imageData)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            set_app_state(app,'busy');
            answer = questdlg('Did you acquire a BSI image?', ...
                'BSI image?', ...
                'Yes','No','No need','No need');
            
            ppwd=pwd;
            cd(generate_path(app));
            app.moreInfo2save.xy_BSI2Map=[];
            app.moreInfo2save.BSI_image_file=[];
            switch answer
                case {'Yes', 'No need'}


                    if strcmp(answer,'Yes')
                        q=dir;
                        qq=q(3:end);
                        fn={qq(arrayfun(@(x) x.isdir, qq)).name};
                        [indx,tf] = listdlg('PromptString',{'Select a folder.',...
                            'Only one folder can be selected at a time.',''},...
                            'SelectionMode','single','ListString',fn);
                       
                        BSI_image_folder = fn{indx};
                         cd(BSI_image_folder)
                        BSI_image_file=strcat(BSI_image_folder,'_MMStack_Default.ome.tif');
                        app.moreInfo2save.BSI_image_file=BSI_image_file;
                        V=tiffreadVolume(BSI_image_file);
V=V-min(min(V));
%x_im_BSI=linspace(0,120,2000); % en um

 imagesc(app.CameraImage, V);
                     %  axis(app.CameraImage,[-Inf Inf -Inf Inf])
                    axis(app.CameraImage,[200 1800 200 1800])
                        set(app.CameraImage, 'Layer', 'top')
                        c=colorbar(app.CameraImage,'SouthOutside');
                        colormap(app.CameraImage,'turbo')
                       % set(app.CameraImage,'ColorScale','log')
                       
                        c.Color='w';
                       

title(app.CameraImage,'Select the area you are measuring','Color','w')
pbaspect(app.CameraImage,[1 1 1])
% xlabel('Distance (um)')
% ylabel('Distance (um)')
% xlim([25 95])
% ylim([30 100])
  app.MainTab.SelectedTab =app.VisuTab;
 [x_BSI2Map, y_BSI2Map] = selectDataPoints(app, app.CameraImage);
 app.moreInfo2save.xy_BSI2Map=[x_BSI2Map, y_BSI2Map];

                       % sprintf('BSI folder is %s',BSI_image_folder)
                    end
                      app.MainTab.SelectedTab = app.MainGraphTab;
                    x_size = app.xsizeSpinner.Value;
                    y_size = app.ysizeSpinner.Value;
                    x_0 = app.x_0Spinner.Value;
                    y_0 = app.y_0Spinner.Value;
                    step_map= app.stepSpinner.Value;
                    do_map(app, x_size, y_size, x_0, y_0, step_map) %start map


                case 'No'
                    add2Log(app,'XYMap cancelled. Try again')
                otherwise
            end

            cd(ppwd);
            set_app_state(app,'idle');
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)

        end

        % Clicked callback: ContinuousAcqToggleTool
        function ContinuousAcqToggleToolClicked(app, event)
            app.ContinuousAcqToggleTool.Enable=false;
            set_app_state(app,'busy')

            focus_on_main_graph(app)

            switch app.DetectorDropDown.Value
                case 'CCD1D'
                    add2Log(app,'Continuous acquisition started')
                    %%
                    readcode = 3; %single-track
                    app.AndorReadoutModeDropDown.Value="Single-track";
                    acqcode=5; %run till abort

                    app.AndorAcqModeDropDown.Value = "Continuous";

                    setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, 0)
                    [ret]=SetShutter(1,0, 0, 0);                 %   Open Shutter IN FULLY AUTO
                    CheckWarning(ret);


                    [ret]=SetSingleTrack(app.centralvalueSpinner.Value, app.heightSpinner.Value);
                    CheckWarning(ret);

                    [ret,xpixels, ~] = GetDetector;           %   Get the CCD size
                    CheckWarning(ret);
                    app.InstrInfo.Spectrometer.xpixels= xpixels;


                    if app.backgroundCheckBox.Value==1  % acquire mon background
                        bkg=acquire_background(app,app.backgroundCheckBox.Value);
                        assignin('base', 'bkg',bkg)
                    else
                        bkg=0.;
                        toggle_shutter(app, 'open',0.01)
                    end

                    [ret] = StartAcquisition();
                    CheckWarning(ret);
                    kk=0; %init counting nb of spectra
                    y_freeze=zeros(2000,1);
                    y_freeze(1)=-42.42 % impossible value, to check y_freeze data is "empty"

                    while kk<500 %max 500 spectrum in a row

                        [ret] = WaitForAcquisition();
                        CheckWarning(ret);

                        [ret, imageData] = GetMostRecentImage(xpixels);
                        CheckWarning(ret);

                        if app.freezeCheckBox.Value==1
                            if y_freeze(1)==-42.42 %check if we just pressed the freeze button

                                GraphicalInterfaceStartup(app,'OrPu')
                                y_freeze=imageData-bkg;
                                plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, y_freeze, 'Color',[241./256., 163./256., 64./256.]);

                                drawnow
                                hold(app.UIAxes, 'on')
                                plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, imageData-bkg,'Color', [153./256., 142./256. ,195./256.]); %plot two curves simultaneously
                            else
                                app.UIAxes.Children(1).YData=imageData-bkg; %change the last plotted curve
                                drawnow
                            end

                        else

                            GraphicalInterfaceStartup(app,'PuOr')
                            y_freeze(1)=-42.42
                            hold(app.UIAxes,'off')
                            plot(app.UIAxes, app.InstrInfo.Spectrometer.calib, imageData-bkg, 'Color',[153./256.,142./256. ,195./256.] ); %continuous acquisition plot
                            switch_eV_nm(app)
                            drawnow
                        end

                        app.LargeCounterField.Value=sum(imageData-bkg);
                        kk=kk+1;
                        %                  pause(0.19)
                        % check pause/stop
                        stopexp = check_if_pause_or_stop(app);
                        if stopexp
                            app.ContinuousAcqToggleTool.State=false;
                            break
                        end
                    end
                    app.freezeCheckBox.Value=0;
                    hold(app.UIAxes,'off')
                    GraphicalInterfaceStartup(app,'PuOr')

                    [ret]=AbortAcquisition;
                    CheckWarning(ret);
                    app.AndorAcqModeDropDown.Value = "Single scan";

                case 'CCD2D'
                    add2Log(app,'Acquisition not fully coded')

                    % set acq params
                    readcode = 4; %Image
                    app.AndorReadoutModeDropDown.Value="Image";
                    acqcode=5; %run till abort
                    app.AndorAcqModeDropDown.Value = "Continuous";

                    setAcqParams(app,acqcode,app.AcqTimeEditField.Value, readcode, 0)
                    [ret]=SetShutter(1,0, 0, 0);                 %   Open Shutter IN FULLY AUTO
                    CheckWarning(ret);

                    % set image ROI
                    h_bin=app.hbinSpinner.Value;
                    v_bin=app.vbinSpinner.Value;
                    h_start=app.hstartSpinner.Value;
                    h_end=app.hendSpinner.Value;
                    v_start=app.vstartSpinner.Value;
                    v_end=app.vendSpinner.Value;

                    [ret]=SetImage(h_bin, v_bin, h_start, h_end, v_start, v_end); %   Set the image size
                    CheckWarning(ret);
                    % ypixels_ROI = 50; ypixels_y0 = 101;
                    xpixels=h_end-h_start+1;
                    ypixels=v_end-v_start+1;
                    [ret,xpixels, ypixels]=GetDetector;           %   Get the CCD size
                    CheckWarning(ret);

                    app.InstrInfo.Spectrometer.ypixels= ypixels;
                    app.InstrInfo.Spectrometer.xpixels= xpixels;


                    app.InstrInfo.CCD.bkg = acquire_background(app,app.backgroundCheckBox.Value);


                    [ret] = StartAcquisition();
                    CheckWarning(ret);

                    kk=1;
                    while kk<5
                        disp('wait')
                        [ret] = WaitForAcquisition();
                        CheckWarning(ret);

                        tic
                        [ret, imageData] = GetMostRecentImage(xpixels.*ypixels);
                        CheckWarning(ret);

                        myImageData = reshape(imageData, xpixels, ypixels);

                        x_axis = app.InstrInfo.Spectrometer.calib;
                        if kk==1
                            p=pcolor(app.UIAxes, x_axis,1:ypixels, flipud(myImageData'));
                        end
                        if app.backgroundCheckBox.Value==true

                            p=pcolor(app.UIAxes, x_axis,1:ypixels, flipud(myImageData')-flipud(reshape(app.InstrInfo.CCD.bkg,app.InstrInfo.Spectrometer.xpixels,app.InstrInfo.Spectrometer.ypixels)'));
                        else
                            p=pcolor(app.UIAxes, x_axis,1:ypixels, flipud(myImageData'));

                        end
                        p.EdgeColor='none';
                        c=colorbar(app.UIAxes); c.Color='w';
                        c.Title.String = "Pixel intensity";
                        c.Title.Color=[1 1 1];
                        [m, ~] = maxk(myImageData(:),2);
                        caxis(app.UIAxes,[min(myImageData(:)) m(2)])
                        xlim(app.UIAxes,[-Inf Inf])
                        ylim(app.UIAxes,[-Inf Inf])
                        ylabel(app.UIAxes,'Pixel')
                        xlabel(app.UIAxes,'Wavelength (nm)')

                        app.LargeCounterField.Value=sum(p.ZData(:));

                        kk=kk+1;
                        % check pause/stop
                        stopexp = check_if_pause_or_stop(app);
                        if stopexp
                            app.ContinuousAcqToggleTool.State=false;
                            break
                        end
                        toc
                        drawnow
                    end

                    [ret]=AbortAcquisition;
                    CheckWarning(ret);


                    app.AndorAcqModeDropDown.Value = "Single scan";
                case {'APDA', 'APDB', 'APDA+B'}

                case 'PD1'
                    add2Log(app,'Continuous acquisition started')

                    k=1;

                    while k<1e3
                        thisx=(k-1).*app.AcqTimeEditField.Value;
                        thisacq = getPDpower_avg(app,app.AcqTimeEditField.Value);
                        if k==1
                            plot(app.UIAxes,[thisx],[thisacq],'s-','MarkerSize',10,'MarkerFaceColor',app.UIAxes.ColorOrder(1,:))
                            xlabel(app.UIAxes,'Time (s)')
                        else
                            app.UIAxes.Children.XData = [app.UIAxes.Children.XData thisx];
                            app.UIAxes.Children.YData = [app.UIAxes.Children.YData thisacq];
                        end
                        app.LargeCounterField.Value=thisacq;
                        k=k+1;
                        drawnow
                        % check pause/stop
                        stopexp = check_if_pause_or_stop(app);
                        if stopexp
                            app.ContinuousAcqToggleTool.State=false;
                            break
                        end
                    end

                case 'BSI'

            end
            toggle_shutter(app,'close', 0)
            app.ContinuousAcqToggleTool.Enable=true;
            app.ContinuousAcqToggleTool.State=false;
            set_app_state(app,'idle')

        end

        % Menu selected function: GetCCDTMenu
        function GetCCDTMenuSelected(app, event)
            [ret, value] = GetTemperature();
            add2Log(app,strcat(strcat(myCheckWarning(ret),"at"),num2str(value)));

            if value<77
                app.CCDTLamp.Color = [0 1 0];
            else
                app.CCDTLamp.Color = [0.5 1 0];
            end
        end

        % Callback function
        function CCDTButtonPushed(app, event)
            [ret, app.InstrInfo.CCD.T] = GetTemperature();
            add2Log(app,num2str(app.InstrInfo.CCD.T));
            if app.InstrInfo.CCD.T < -75.
                app.LampStatusT.Color = [0 255 0]./255;
            else
                app.LampStatusT.Color = [255  0 0]./255;
            end
        end

        % Button pushed function: VisusliderOFFButton
        function VisusliderOFFButtonPushed(app, event)
            go_home( app.InstrInfo.ELL_linear.serialdev,  app.InstrInfo.ELL_linear.device_adress, app.InstrInfo.ELL_linear.type)
            %%%%%
            %pause(0.1)
            %imagesc(app.CameraImage,zeros(1080,1440)+eye(1080,1440))
            %             app.CustMovieStopButton.Value=0;
            % turn off led
            V_LED=0;
            V_y= micron2volt(app, app.FSM_yREditField.Value);
            V_x = micron2volt(app, app.FSM_xREditField.Value);
            send_DAQ_output(app,V_x,V_y,V_LED);
        end

        % Menu selected function: PathsMenu
        function PathsMenuSelected(app, event)
            prompt = {'Application base path:','Data base path:'};
            dlgtitle = 'Update paths';
            dims = [1 35];
            definput = {'C:\Users\tharrault\Documents\CARODOTManip','C:\Users\tharrault\Documents\CARODAT'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.AppInfo.basepath_app = answer{1};
            app.AppInfo.basepath_data = answer{2};
        end

        % Value changed function: GratingDropDown
        function GratingDropDownValueChanged(app, event)
            set_app_state(app,'busy')
            value = app.GratingDropDown.Value;

            switch value
                case '150'
                    [ret] = ATSpectrographSetGrating(0,1);
                    ATSpectrographCheckWarning(ret);
                case '600'
                    [ret] = ATSpectrographSetGrating(0,2);
                    ATSpectrographCheckWarning(ret);
                case '1200'
                    [ret] = ATSpectrographSetGrating(0,3);
                    ATSpectrographCheckWarning(ret);
            end
            [ret, spectro.calib] = ATSpectrographGetCalibration(0,app.InstrInfo.CCD.xpixels);
            ATSpectrographCheckWarning(ret);
            [ret, spectro.currGrating] = ATSpectrographGetGrating(0);
            ATSpectrographCheckWarning(ret);
            [ret, spectro.currGratingInfo(spectro.currGrating).lines, spectro.currGratingInfo(spectro.currGrating).blaze, spectro.currGratingInfo(spectro.currGrating).home, spectro.currGratingInfo(spectro.currGrating).offset] = ATSpectrographGetGratingInfo(0,spectro.currGrating,256);
            ATSpectrographCheckWarning(ret);
            app.InstrInfo.Spectrometer = spectro;

            BkgValueChanged(app,event)

            set_app_state(app,'idle')
        end

        % Selection change function: MeasurementControlsTab
        function MeasurementControlsTabSelectionChanged(app, event)
            selectedTab = app.MeasurementControlsTab.SelectedTab;

        end

        % Value changed function: PolarDetTargetEditField, 
        % PolarExcTargetEditField, TargetEditField, TargetEditField_2, 
        % TargetEditField_3
        function TargetEditFieldValueChanged(app, event)
            switch event.Source.Tag
                case 'Trg1'

                    position = app.TargetEditField.Value;
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos=set_position(elldevice, 1, position);
                    %                     pos = move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position)
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    app.ReadoutEditField.Value = pos

                case 'Trg2'

                    position = app.TargetEditField_2.Value;
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos=set_position(elldevice, 2, position);
                    %                     elldevice = app.InstrInfo.ELL_rotA(2);
                    %                     pos = move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position);
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    app.ReadoutEditField_2.Value = pos;

                case 'Trg3'
                    position = app.TargetEditField_3.Value;
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos=set_position(elldevice, 3, position);
                    %                     elldevice = app.InstrInfo.ELL_rotA(3);
                    %                     pos = move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position);
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    app.ReadoutEditField_3.Value = pos;


                case 'PolarExcTrg'

                    position = app.PolarExcTargetEditField.Value;
                    elldevice = app.InstrInfo.ELL_shutterpol;
                    pos=set_position(elldevice, 2, position);
                    %                     pos = move_absolute(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type, position);
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                    app.PolarExcReadoutEditField.Value = pos;

                case 'PolarDetTrg'

                    position = app.PolarDetTargetEditField.Value;
                    elldevice = app.InstrInfo.ELL_detpol;
                    pos=set_position(elldevice, 0, position);
                    %                     pos = move_absolute(elldevice.serialdev,  elldevice.device_adress, elldevice.type, position);
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    app.PolarDetReadoutEditField.Value = pos;


            end


        end

        % Button pushed function: FwdButton, FwdButton_2, FwdButton_3, 
        % PolarDetJogFw, PolarExcJogFw
        function FwdButtonPushed(app, event)
            switch event.Source.Tag
                case 'FWD1'
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos = jog(elldevice, 1, 'fwd');
                    %                     elldevice = app.InstrInfo.ELL_rotA(1);
                    %                     pos = step_forward(elldevice.serialdev,  elldevice.device_adress,'RS');
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress,'RS');
                    app.ReadoutEditField.Value = pos;

                case 'FWD2'
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos = jog(elldevice, 2, 'fwd');
                    %                     elldevice = app.InstrInfo.ELL_rotA(2);
                    %                     pos = step_forward(elldevice.serialdev,  elldevice.device_adress,'RS');
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress,'RS');
                    app.ReadoutEditField_2.Value = pos;
                case 'FWD3'
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos = jog(elldevice, 3, 'fwd');
                    %                     elldevice = app.InstrInfo.ELL_rotA(3);
                    %                     pos = step_forward(elldevice.serialdev,  elldevice.device_adress,'RS');
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress,'RS');
                    app.ReadoutEditField_3.Value = pos;
                case 'PolarExcFWD'
                    elldevice = app.InstrInfo.ELL_shutterpol;
                    pos = jog(elldevice, 2, 'fwd');
                    %                     pos = step_forward(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                    %                     pos = read_pos(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                    app.PolarExcReadoutEditField.Value = pos;
                case 'PolarDetFWD'
                    elldevice=app.InstrInfo.ELL_detpol;
                    pos = jog(elldevice, 0, 'fwd');
                    %                     elldevice = app.InstrInfo.ELL_detpol;
                    %                     pos = step_forward(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    %                     pos = read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    app.PolarDetReadoutEditField.Value = pos;
            end
        end

        % Button pushed function: BwdButton, BwdButton_2, BwdButton_3, 
        % PolarDetJogBk, PolarExcJogBk
        function BwdButtonPushed(app, event)
            switch event.Source.Tag
                case 'BWD1'
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos = jog(elldevice, 1, 'bwd');
                    %                     elldevice = app.InstrInfo.ELL_rotA(1);
                    %                     pos = step_backward(elldevice.serialdev,  elldevice.device_adress,'RS');
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress,'RS');
                    app.ReadoutEditField.Value = pos;
                case 'BWD2'
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos = jog(elldevice, 2, 'bwd');
                    %                     elldevice = app.InstrInfo.ELL_rotA(2);
                    %                     pos = step_backward(elldevice.serialdev,  elldevice.device_adress,'RS');
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    app.ReadoutEditField_2.Value = pos;
                case 'BWD3'
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    pos = jog(elldevice, 3, 'bwd');
                    %                     elldevice = app.InstrInfo.ELL_rotA(3);
                    %                     pos = step_backward(elldevice.serialdev,  elldevice.device_adress,'RS');
                    %                     pos=read_pos(elldevice.serialdev,  elldevice.device_adress,'RS');
                    app.ReadoutEditField_3.Value = pos;
                case 'PolarExcBWD'
                    elldevice = app.InstrInfo.ELL_shutterpol;
                    pos = jog(elldevice, 2, 'bwd');
                    %                     pos = step_backward(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                    %                     pos = read_pos(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type);
                    app.PolarExcReadoutEditField.Value = pos;
                case 'PolarDetBWD'
                    %                     elldevice = app.InstrInfo.ELL_detpol;
                    %                     pos = step_backward(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    %                     pos = read_pos(elldevice.serialdev,  elldevice.device_adress, elldevice.type);
                    %                     app.PolarDetReadoutEditField.Value = pos;
                    elldevice=app.InstrInfo.ELL_detpol;
                    pos = jog(elldevice, 0, 'bwd');
                    app.PolarDetReadoutEditField.Value = pos;
            end
        end

        % Value changed function: PolarDetJogStepEditField, 
        % PolarExcJogStepEditField, StepEditField, StepEditField_2, 
        % StepEditField_3
        function StepEditFieldValueChanged(app, event)
            switch event.Source.Tag
                case 'STP1'
                    step = app.StepEditField.Value;
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    set_jogstep(elldevice, 1, step);
                    %                     elldevice = app.InstrInfo.ELL_rotA(1);
                    %                     set_jogstep(elldevice.serialdev,  elldevice.device_adress, elldevice.type, step)
                case 'STP2'
                    step = app.StepEditField_2.Value;
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    set_jogstep(elldevice, 2, step);
                    %                     elldevice = app.InstrInfo.ELL_rotA(2);
                    %                     set_jogstep(elldevice.serialdev,  elldevice.device_adress, elldevice.type, step)
                case 'STP3'
                    step = app.StepEditField_3.Value;
                    elldevice=app.InstrInfo.ELL_TWTBP1;
                    set_jogstep(elldevice, 3, step);
                    %                     elldevice = app.InstrInfo.ELL_rotA(3);
                    %                     set_jogstep(elldevice.serialdev,  elldevice.device_adress, elldevice.type, step)
                case 'PolarExcSTP'
                    step = app.PolarExcJogStepEditField.Value;
                    elldevice = app.InstrInfo.ELL_shutterpol;
                    set_jogstep(elldevice, 2, step);
                    %                     set_jogstep(elldevice.serialdev,  elldevice.device_adress_pol, elldevice.device_adress_pol_type, step);
                case 'PolarDetSTP'
                    step = app.PolarDetJogStepEditField.Value;
                    elldevice=app.InstrInfo.ELL_detpol;
                    set_jogstep(elldevice, 0, step);
                    %                     elldevice = app.InstrInfo.ELL_detpol;
                    %                     set_jogstep(elldevice.serialdev,  elldevice.device_adress, elldevice.type, step)
            end
        end

        % Value changed function: instrDropDown
        function instrDropDownValueChanged(app, event)
            value = app.instrDropDown.Value;

            switch value
                case 'TWTBPFilter A'
                    app.instrunitEditField.Value = 'deg';
                    app.startEditField.Limits = [-360 360]; app.startEditField.Value = 40;
                    app.endEditField.Limits = [-360 360]; app.endEditField.Value = 60;
                    app.stepEditField.Limits = [-360 360]; app.stepEditField.Value = 1;
                case {'BP1A', 'BP2A', 'CPA'}
                    app.instrunitEditField.Value = 'deg';
                    app.startEditField.Limits = [0 360]; app.startEditField.Value = 40;
                    app.endEditField.Limits = [0 360]; app.endEditField.Value = 60;
                    app.stepEditField.Limits = [-360 360]; app.stepEditField.Value = 1;
                case 'ExcPol'
                    app.instrunitEditField.Value = 'deg';
                    app.startEditField.Limits = [-720 720]; app.startEditField.Value = 0;
                    app.endEditField.Limits = [-720 720]; app.endEditField.Value = 180;
                    app.stepEditField.Limits = [-720 720]; app.stepEditField.Value = 5;
                case 'DetPol'
                    app.instrunitEditField.Value = 'deg';
                    app.startEditField.Limits = [-720 720]; app.startEditField.Value = 0;
                    app.endEditField.Limits = [-720 720]; app.endEditField.Value = 180;
                    app.stepEditField.Limits = [-720 720]; app.stepEditField.Value = 5;
                case {'Spectrometer','CCD iDus'}
                    app.instrunitEditField.Value = 'nm';
                    app.startEditField.Limits = [200 1200]; app.startEditField.Value = 400;
                    app.endEditField.Limits = [200 1200]; app.endEditField.Value = 600;
                    app.stepEditField.Limits = [-500 500]; app.stepEditField.Value = 40;
                case 'LED'
                    app.instrunitEditField.Value = 'V';
                    app.startEditField.Limits = [0 5]; app.startEditField.Value = 0;
                    app.endEditField.Limits = [0 5]; app.endEditField.Value = 5;
                    app.stepEditField.Limits = [-5 5]; app.stepEditField.Value = 1;
                otherwise
            end
        end

        % Value changed function: MarkerUpEditField
        function MarkerUpEditFieldValueChanged(app, event)
            value = app.MarkerUpEditField.Value;
            generate_filename(app);
        end

        % Value changed function: MarkerDownEditField
        function MarkerDownEditFieldValueChanged(app, event)
            value = app.MarkerDownEditField.Value;
            generate_filename(app);
        end

        % Value changed function: NCSpinner
        function NCSpinnerValueChanged(app, event)
            value = app.NCSpinner.Value;
            generate_filename(app);
        end

        % On callback: AutosaveToggleTool
        function AutosaveToggleToolOn(app, event)
            % when autosave is turned on
            app.SaveTool.Visible = false; app.SaveTool.Enable = false; % disable manual save button
            app.AutosaveToggleTool.Tooltip = "Autosave: on!";
            app.AutosaveToggleTool.Icon = [ app.AppInfo.basepath_app + "\design\icons\Diapositive12.PNG"];
        end

        % Clicked callback: SaveTool
        function SaveToolClicked(app, event)
            % when autosave off this can be used to save last measurement
        end

        % Off callback: AutosaveToggleTool
        function AutosaveToggleToolOff(app, event)
            % when autosave is turned off
            app.SaveTool.Visible = true; app.SaveTool.Enable = true; % enable manual save button
            app.AutosaveToggleTool.Tooltip = "Autosave: off; use manual save";
            app.AutosaveToggleTool.Icon = [app.AppInfo.basepath_app + "\design\icons\Diapositive13.PNG"];
        end

        % Button pushed function: MasterRunButton
        function MasterRunButtonPushed(app, event)
            set_app_state(app,'busy')

            x_start = app.startEditField.Value;
            x_end = app.endEditField.Value;
            x_step = app.stepEditField.Value;

            x_list = x_start:x_step:x_end;

            master_init_meas(app);


            toggle_shutter(app,'open',0)

            app.UIAxes.Title.String = "Hello";
            app.UIAxes.Title.Color = "w";
            app.UIAxes.TitleHorizontalAlignment="left";
            YY = NaN(2000,length(x_list));

            %             p=pcolor(app.map_image,1:2000,1:length(x_list),YY');
            %             p.EdgeColor='none';
%             imagesc(app.map_image,1:2000,1:length(x_list),YY');
 imagesc(app.map_image,1:2000,x_list,YY');
            xcorr_list = [];

            %             colorbar(app.map_image)
            for i=1:length(x_list)
                thisx = x_list(i);

                %%%%%%%%%%%
                tic
                [corr_x] = master_move(app, thisx);
                thiscorrx = corr_x;
                xcorr_list = [xcorr_list thiscorrx];
                toc1 = toc;
                tic
                [~, output, ~, wavelength, thisacq] = master_acquire(app);
                add2Log(app,sprintf('move %.2f acq %.2f',toc1,toc))
                %%%%%%%%%%%
                YY(:,i) = output;
                if ~isnan(output)
                    app.map_image.Children.CData = YY';
                    axis(app.map_image,'xy')
                    axis(app.map_image,[-Inf Inf x_list(1)-1./2 x_list(end)+1./2])
                    c=colorbar(app.map_image,'SouthOutside');
                    c.Color='w';
                else
                    if i==1
                        plot(app.UIAxes,[thisx],[thisacq],'s-','MarkerSize',15,'MarkerFaceColor',app.UIAxes.ColorOrder(1,:))
                        %                         app.UIAxes.Title.String = sprintf('%d/%d: %d - %d',i, length(x_list), thisx, thiscorrx);
                        %                         app.UIAxes.Title.Color = 'w';

                    else
                        app.UIAxes.Children.XData = [app.UIAxes.Children.XData thisx];
                        app.UIAxes.Children.YData = [app.UIAxes.Children.YData thisacq];
                    end
                end
                app.UIAxes.Title.String = sprintf('%d/%d: %.1f - %.1f',i, length(x_list), thisx, thiscorrx);
                app.UIAxes.Title.Color = 'w';
                drawnow
                % check pause/stop
                stopexp = check_if_pause_or_stop(app);
                if stopexp
                    break
                end
            end

            if app.PWnexttoBSCheckBox.Value == 0 %% measure power if not acquired during the spectrum meas
                answer = questdlg('Is powermeter ready to acquire?');
                if strcmp(answer,'Yes')
                    xcorr_list = [];
                    for i=1:length(x_list)
                        thisx = x_list(i);
                        [corr_x] = master_move(app, thisx);
                        thiscorrx = corr_x;
                        xcorr_list = [xcorr_list thiscorrx];
                    end
                else 

                end

            end
            assignin('base','xcorr',xcorr_list)
            app.moreInfo2save.Power_list=xcorr_list;

            toggle_shutter(app,'close',0)
            save_data(app,app.thismeas)
           % disp(app.thismeas)
            set_app_state(app,'idle')
        end

        % Menu selected function: BSIMenu, LinearMenu, ProgrammingCCDMenu, 
        % ProgrammingMenu, RotationmountMenu, RotationstageMenu, 
        % posSliderMenu
        function ProgrammingMenuSelected(app, event)
            switch event.Source.Text
                case 'Rotation stage'
                    open([app.AppInfo.basepath_app + "\Manuals\ELL18K_M_RS-Manual.pdf"])
                case 'Rotation mount'
                    open([app.AppInfo.basepath_app + "\Manuals\ELL14_RM-Manual.pdf"])
                case 'Linear'
                    open([app.AppInfo.basepath_app + "\Manuals\ELL20K_M_L-Manual.pdf"])
                case '2-pos slider'
                    open([app.AppInfo.basepath_app + "\Manuals\ELL6_L-Manual.pdf"])
                case 'Programming'
                    open([app.AppInfo.basepath_app + "\Manuals\ELLx modules protocol manual.pdf"])
                case 'BSI'
                    open([app.AppInfo.basepath_app + "\Manuals\BSI-Express-Manual-Rev-A10-08032023.pdf"])
                case 'ProgrammingCCDMenu'
                    open([ app.AppInfo.basepath_app + "\Manuals\Andor_Software_Development_Kit_2.pdf"])
            end

        end

        % Value changed function: AcqTimeEditField, DetectorDropDown
        function BkgValueChanged(app, event)
            delete_background(app)
        end

        % Value changed function: GainDropDown
        function GainDropDownValueChanged(app, event)
            value = app.GainDropDown.Value;
            BkgValueChanged(app,event)
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % Create a temporary figure with axes.
            fig = figure;
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.ZLim = app.UIAxes.ZLim;
            xlabel(figAxes,app.UIAxes.XLabel.String)
            ylabel(figAxes,app.UIAxes.YLabel.String)
            zlabel(figAxes,app.UIAxes.ZLabel.String)
            box(figAxes,'on')
            grid(figAxes,'on')

            ppwd=pwd;
            cd(generate_path(app))
            filter = {'*.fig','MATLAB Figure';
                '*.png', 'PNG image';...
                '*.csv','Text CSV'};
            ladate = string(datetime("now",'Format','yyyy-MM-dd'));
            exportedfilename = [ladate + "_" + app.DetectorDropDown.Value + "_" + sprintf('%.0fms',app.AcqTimeEditField.Value*1e3) + ".fig"];
            filename = uiputfile(filter,'Select file and filetype',exportedfilename);


            tmp=arrayfun(@(x) strcmp(x,"CData"), fieldnames(app.UIAxes.Children), 'UniformOutput', false);

            switch filename(end-3:end)
                case '.fig'
                    savefig(fig, filename);
                case '.png'
                    saveas(fig, filename, 'png');
                case '.csv'
                    if isempty(app.UIAxes.Children.ZData) & ~any([tmp{:}]')
                        %                         if isempty(app.UIAxes.Children.CData)
                        OUT = [app.UIAxes.Children.XData' app.UIAxes.Children.YData'];
                        writematrix(OUT,filename)
                        assignin('base','OUT',OUT)
                        %                         end
                    else
                        warning('Cant be saved yet')
                    end
            end
            % savefig(fig, fileName);
            % Delete the temporary figure.
            delete(fig);
            cd(ppwd)
        end

        % Menu selected function: PrinttofigureMenu
        function PrinttofigureMenuSelected(app, event)

            %             assignin('base','f',event.Source)

            switch event.Source.Parent.Parent.CurrentObject.Tag
                case "MainGraph"
                    myax = app.UIAxes;
                case "SideGraph"
                    myax = app.map_image;
                otherwise
            end
            % Create a temporary figure with axes.
            fig = figure;
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            allChildren = myax.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = myax.XLim;
            figAxes.YLim = myax.YLim;
            figAxes.ZLim = myax.ZLim;
            xlabel(figAxes,myax.XLabel.String)
            ylabel(figAxes,myax.YLabel.String)
            zlabel(figAxes,myax.ZLabel.String)
            box(figAxes,'on')
            grid(figAxes,'on')

        end

        % Menu selected function: appandallconnectionsMenu, 
        % appandkeepspectrographMenu, restartappMenu
        function restartappMenuSelected(app, event)
            app.AppInfo.restart_mode = event.Source.Text;

            closeAllDevices(app)
            pause(0.2)
            startupFcn(app)

        end

        % Value changed function: conversionSwitch
        function conversionSwitchValueChanged(app, event)
            %value = app.conversionSwitch.Value;
            switch_eV_nm(app)

        end

        % Button pushed function: Go2PointButton
        function Go2PointButtonPushed(app, event)
            roi = drawpoint(app.map_image);
            x = roi.Position(1);
            y = roi.Position(2);

            delete(roi)

            app.FSM_xTEditField.Value = x;
            app.FSM_yTEditField.Value = y;

            V_x = micron2volt(app, app.FSM_xTEditField.Value);
            V_y = micron2volt(app, app.FSM_yTEditField.Value);
            V_LED = app.LEDKnob.Value;

            send_DAQ_output(app,V_x,V_y,V_LED)

        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            x_0 = app.x_0Spinner.Value;
            y_0 = app.y_0Spinner.Value;
            x_size = app.xsizeSpinner.Value;
            y_size = app.ysizeSpinner.Value;
            step_map = app.stepSpinner.Value;

            % create x and y pos list beforehand
            xmap = -[0:x_size-1].*step_map + x_0;
            ymap = [0:y_size-1].*step_map + y_0;


            [minx, maxx] = bounds(xmap)
            [miny, maxy] = bounds(ymap)
        end

        % Button pushed function: addcomButton
        function addcomButtonPushed(app, event)
            fullmatfilepath = [generate_path(app) + "\" + generate_filename(app) + ".mat"];
allmeas=load(fullmatfilepath); allmeas=allmeas.allmeas;

            fns=fields(allmeas);
dates=NaT(1,length(fns));
for i=1:length(fns)
dates(i)=datetime(allmeas.(fns{i})(end).Date);
end
[~,i]=max(dates);
allmeas.(fns{i})(end).Comment= strcat(allmeas.(fns{i})(end).Comment , ' / ', app.Comment.Value{:});
app.Comment.Value='';
save(fullmatfilepath, 'allmeas')

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %map duration in min
            app.MapduratminEditField.Value=(app.xsizeSpinner.Value./app.stepSpinner.Value+1).*(app.ysizeSpinner.Value./app.stepSpinner.Value+1).*app.AcqTimeEditField.Value./60 ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CARODOTManipUIFigure = uifigure('Visible', 'off');
            app.CARODOTManipUIFigure.Name = 'CARODOTManip';
            app.CARODOTManipUIFigure.CloseRequestFcn = createCallbackFcn(app, @CARODOTManipUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.CARODOTManipUIFigure);
            app.FileMenu.Text = 'File';
            app.NewfileMenu = uimenu(app.FileMenu);
            app.NewfileMenu.Enable = 'off';
            app.NewfileMenu.Text = 'New file';
            app.LoadfileMenu = uimenu(app.FileMenu);
            app.LoadfileMenu.Enable = 'off';
            app.LoadfileMenu.Text = 'Load file';
            app.OptionsMenu = uimenu(app.FileMenu);
            app.OptionsMenu.Enable = 'off';
            app.OptionsMenu.Text = 'Options...';
            app.RestartMenu = uimenu(app.FileMenu);
            app.RestartMenu.Tooltip = {'Not recommended, exit app and relaunch instead'};
            app.RestartMenu.Text = 'Restart';
            app.appandallconnectionsMenu = uimenu(app.RestartMenu);
            app.appandallconnectionsMenu.MenuSelectedFcn = createCallbackFcn(app, @restartappMenuSelected, true);
            app.appandallconnectionsMenu.Text = 'app and all connections';
            app.appandkeepspectrographMenu = uimenu(app.RestartMenu);
            app.appandkeepspectrographMenu.MenuSelectedFcn = createCallbackFcn(app, @restartappMenuSelected, true);
            app.appandkeepspectrographMenu.Text = 'app and keep spectrograph';
            app.restartappMenu = uimenu(app.RestartMenu);
            app.restartappMenu.MenuSelectedFcn = createCallbackFcn(app, @restartappMenuSelected, true);
            app.restartappMenu.Visible = 'off';
            app.restartappMenu.Enable = 'off';
            app.restartappMenu.Text = 'restartapp';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.CARODOTManipUIFigure);
            app.EditMenu.Text = 'Edit';
            app.PathsMenu = uimenu(app.EditMenu);
            app.PathsMenu.MenuSelectedFcn = createCallbackFcn(app, @PathsMenuSelected, true);
            app.PathsMenu.Text = 'Paths';
            app.ViewMenu = uimenu(app.CARODOTManipUIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.CARODOTManipUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.ExportapptoworkspaceMenu = uimenu(app.HelpMenu);
            app.ExportapptoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportapptoworkspaceMenuSelected, true);
            app.ExportapptoworkspaceMenu.Text = 'Export app to workspace';
            app.ElliptecMenu = uimenu(app.HelpMenu);
            app.ElliptecMenu.Text = 'Elliptec';
            app.DeviceMenu = uimenu(app.ElliptecMenu);
            app.DeviceMenu.Text = 'Device';
            app.RotationstageMenu = uimenu(app.DeviceMenu);
            app.RotationstageMenu.MenuSelectedFcn = createCallbackFcn(app, @ProgrammingMenuSelected, true);
            app.RotationstageMenu.Text = 'Rotation stage';
            app.RotationmountMenu = uimenu(app.DeviceMenu);
            app.RotationmountMenu.MenuSelectedFcn = createCallbackFcn(app, @ProgrammingMenuSelected, true);
            app.RotationmountMenu.Text = 'Rotation mount';
            app.LinearMenu = uimenu(app.DeviceMenu);
            app.LinearMenu.MenuSelectedFcn = createCallbackFcn(app, @ProgrammingMenuSelected, true);
            app.LinearMenu.Text = 'Linear';
            app.posSliderMenu = uimenu(app.DeviceMenu);
            app.posSliderMenu.MenuSelectedFcn = createCallbackFcn(app, @ProgrammingMenuSelected, true);
            app.posSliderMenu.Text = '2-pos Slider';
            app.ProgrammingMenu = uimenu(app.ElliptecMenu);
            app.ProgrammingMenu.MenuSelectedFcn = createCallbackFcn(app, @ProgrammingMenuSelected, true);
            app.ProgrammingMenu.Text = 'Programming';
            app.AndorMenu = uimenu(app.HelpMenu);
            app.AndorMenu.Text = 'Andor';
            app.SpectrometerMenu = uimenu(app.AndorMenu);
            app.SpectrometerMenu.Text = 'Spectrometer';
            app.CCDMenu = uimenu(app.AndorMenu);
            app.CCDMenu.Text = 'CCD';
            app.ProgrammingspectrometerMenu = uimenu(app.AndorMenu);
            app.ProgrammingspectrometerMenu.Text = 'Programming spectrometer';
            app.ProgrammingCCDMenu = uimenu(app.AndorMenu);
            app.ProgrammingCCDMenu.MenuSelectedFcn = createCallbackFcn(app, @ProgrammingMenuSelected, true);
            app.ProgrammingCCDMenu.Text = 'Programming CCD';
            app.BSIMenu = uimenu(app.HelpMenu);
            app.BSIMenu.MenuSelectedFcn = createCallbackFcn(app, @ProgrammingMenuSelected, true);
            app.BSIMenu.Text = 'BSI';
            app.Toolbar = uitoolbar(app.CARODOTManipUIFigure);
            app.AppStateToggleTool = uitoggletool(app.Toolbar);
            app.AppStateToggleTool.Tooltip = {'App status'};
            app.AppStateToggleTool.Separator = 'on';
            app.SaveTool = uipushtool(app.Toolbar);
            app.SaveTool.Visible = 'off';
            app.SaveTool.Tooltip = {'Manual save'};
            app.SaveTool.ClickedCallback = createCallbackFcn(app, @SaveToolClicked, true);
            app.SaveTool.Enable = 'off';
            app.SaveTool.Icon = 'Diapositive11.PNG';
            app.SaveTool.Separator = 'on';
            app.AutosaveToggleTool = uitoggletool(app.Toolbar);
            app.AutosaveToggleTool.Tooltip = {'Autosave on'};
            app.AutosaveToggleTool.Icon = 'Diapositive12.PNG';
            app.AutosaveToggleTool.OffCallback = createCallbackFcn(app, @AutosaveToggleToolOff, true);
            app.AutosaveToggleTool.OnCallback = createCallbackFcn(app, @AutosaveToggleToolOn, true);
            app.AutosaveToggleTool.State = 'on';
            app.PauseTool = uitoggletool(app.Toolbar);
            app.PauseTool.Tooltip = {'Pause measurement'};
            app.PauseTool.Enable = 'off';
            app.PauseTool.Icon = 'Diapositive5.PNG';
            app.PauseTool.Separator = 'on';
            app.StopTool = uitoggletool(app.Toolbar);
            app.StopTool.Tooltip = {'Stop measurement'};
            app.StopTool.Enable = 'off';
            app.StopTool.Icon = 'Diapositive6.PNG';
            app.AcquirePicturePushTool = uipushtool(app.Toolbar);
            app.AcquirePicturePushTool.Tooltip = {'Visualise the sample surface'};
            app.AcquirePicturePushTool.ClickedCallback = createCallbackFcn(app, @ButtonPushed, true);
            app.AcquirePicturePushTool.Icon = 'Diapositive1.PNG';
            app.AcquirePicturePushTool.Separator = 'on';
            app.AcquireCustMoviePushTool = uipushtool(app.Toolbar);
            app.AcquireCustMoviePushTool.ClickedCallback = createCallbackFcn(app, @AcquireCustMoviePushToolClicked, true);
            app.AcquireCustMoviePushTool.Icon = 'Diapositive10.PNG';
            app.NewMoviePushTool = uipushtool(app.Toolbar);
            app.NewMoviePushTool.ClickedCallback = createCallbackFcn(app, @NewMoviePushToolClicked, true);
            app.shutter_tool = uipushtool(app.Toolbar);
            app.shutter_tool.Tooltip = {'Toggle shutter'};
            app.shutter_tool.ClickedCallback = createCallbackFcn(app, @shutter_toolClicked, true);
            app.shutter_tool.Icon = 'Diapositive8.PNG';
            app.shutter_tool.Separator = 'on';
            app.FastAcquire = uipushtool(app.Toolbar);
            app.FastAcquire.Tooltip = {'Acquire'};
            app.FastAcquire.ClickedCallback = createCallbackFcn(app, @FastAcquireClicked, true);
            app.FastAcquire.Icon = 'Diapositive2.PNG';
            app.FastAcquire.Separator = 'on';
            app.ContinuousAcqToggleTool = uitoggletool(app.Toolbar);
            app.ContinuousAcqToggleTool.Tooltip = {'Continuous acq. with selected detector'};
            app.ContinuousAcqToggleTool.ClickedCallback = createCallbackFcn(app, @ContinuousAcqToggleToolClicked, true);
            app.ContinuousAcqToggleTool.Icon = 'Diapositive3.PNG';
            app.ContinuousAcqPushTool = uipushtool(app.Toolbar);
            app.ContinuousAcqPushTool.Tooltip = {'Continuous'};
            app.ContinuousAcqPushTool.ClickedCallback = createCallbackFcn(app, @ContinuousAcqPushToolClicked, true);
            app.AppGridLayout = uigridlayout(app.CARODOTManipUIFigure);
            app.MainTab = uitabgroup(app.AppGridLayout);
            app.MainGraphTab = uitab(app.MainTab);
            app.MainGraphTab.Title = 'Main Graph';
            app.UIAxes = uiaxes(app.MainGraphTab);
            title(app.UIAxes, 'MyTitle')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'MainGraph';
            app.LargeCounterField = uieditfield(app.MainGraphTab, 'numeric');
            app.LargeCounterField.ValueDisplayFormat = '%1.3g';
            app.LargeCounterField.Editable = 'off';
            app.CounterdisplayDropDownLabel = uilabel(app.MainGraphTab);
            app.CounterdisplayDropDownLabel.Text = 'Counter display';
            app.CounterdisplayDropDown = uidropdown(app.MainGraphTab);
            app.CounterdisplayDropDown.Items = {'Sum', 'Max', 'Sum/s', 'Max/s'};
            app.CounterdisplayDropDown.Value = 'Sum';
            app.conversionSwitchLabel = uilabel(app.MainGraphTab);
            app.conversionSwitchLabel.Text = 'conversion';
            app.conversionSwitch = uiswitch(app.MainGraphTab, 'slider');
            app.conversionSwitch.Items = {'nm', 'eV'};
            app.conversionSwitch.ValueChangedFcn = createCallbackFcn(app, @conversionSwitchValueChanged, true);
            app.conversionSwitch.Value = 'nm';
            app.VisuTab = uitab(app.MainTab);
            app.VisuTab.Title = 'Visu';
            app.CameraImage = uiaxes(app.VisuTab);
            title(app.CameraImage, 'Title')
            xlabel(app.CameraImage, 'X')
            ylabel(app.CameraImage, 'Y')
            zlabel(app.CameraImage, 'Z')
            app.CameraImage_2 = uiaxes(app.VisuTab);
            title(app.CameraImage_2, 'Title')
            xlabel(app.CameraImage_2, 'X')
            ylabel(app.CameraImage_2, 'Y')
            zlabel(app.CameraImage_2, 'Z')
            app.CustMovieStopButton = uibutton(app.VisuTab, 'state');
            app.CustMovieStopButton.ValueChangedFcn = createCallbackFcn(app, @CustMovieStopButtonValueChanged, true);
            app.CustMovieStopButton.Text = 'Stop';
            app.VisusliderOFFButton = uibutton(app.VisuTab, 'push');
            app.VisusliderOFFButton.ButtonPushedFcn = createCallbackFcn(app, @VisusliderOFFButtonPushed, true);
            app.VisusliderOFFButton.Text = 'Visu slider OFF';
            app.VisusliderONButton = uibutton(app.VisuTab, 'push');
            app.VisusliderONButton.ButtonPushedFcn = createCallbackFcn(app, @VisusliderONButtonPushed, true);
            app.VisusliderONButton.Text = 'Visu slider ON';
            app.SettingsTab = uitab(app.MainTab);
            app.SettingsTab.Title = 'Settings';
            app.Image3 = uiimage(app.SettingsTab);
            app.Image3.ImageSource = 'setup_vnow.svg';
            app.SteeringmirrorsPanel = uipanel(app.SettingsTab);
            app.SteeringmirrorsPanel.Title = 'Steering mirror(s)';
            app.FSM_x0EditFieldLabel = uilabel(app.SteeringmirrorsPanel);
            app.FSM_x0EditFieldLabel.Enable = 'off';
            app.FSM_x0EditFieldLabel.Text = 'FSM_x0';
            app.FSM_x0EditField = uieditfield(app.SteeringmirrorsPanel, 'numeric');
            app.FSM_x0EditField.Enable = 'off';
            app.FSM_y0EditFieldLabel = uilabel(app.SteeringmirrorsPanel);
            app.FSM_y0EditFieldLabel.Enable = 'off';
            app.FSM_y0EditFieldLabel.Text = 'FSM_y0';
            app.FSM_y0EditField = uieditfield(app.SteeringmirrorsPanel, 'numeric');
            app.FSM_y0EditField.Enable = 'off';
            app.FSM_y0EditField.Value = -6.66;
            app.SelectedInstruments = uitree(app.SettingsTab, 'checkbox');
            app.LightgenerationandcontrolNode = uitreenode(app.SelectedInstruments);
            app.LightgenerationandcontrolNode.Text = 'Light generation and control';
            app.LEDNode = uitreenode(app.LightgenerationandcontrolNode);
            app.LEDNode.Text = 'LED';
            app.BSsliderNode = uitreenode(app.LightgenerationandcontrolNode);
            app.BSsliderNode.Text = 'BS slider';
            app.ShutterNode = uitreenode(app.LightgenerationandcontrolNode);
            app.ShutterNode.Text = 'Shutter';
            app.ExcitationpolarisationNode = uitreenode(app.LightgenerationandcontrolNode);
            app.ExcitationpolarisationNode.Text = 'Excitation polarisation';
            app.SteeringmirrorNode = uitreenode(app.SelectedInstruments);
            app.SteeringmirrorNode.Text = 'Steering mirror';
            app.DetectorsNode = uitreenode(app.SelectedInstruments);
            app.DetectorsNode.Text = 'Detectors';
            app.ZeluxcameraNode = uitreenode(app.DetectorsNode);
            app.ZeluxcameraNode.Text = 'Zelux camera';
            app.APDMPDNode = uitreenode(app.DetectorsNode);
            app.APDMPDNode.Text = 'APD MPD';
            app.CCDiDusNode = uitreenode(app.DetectorsNode);
            app.CCDiDusNode.Text = 'CCD iDus';
            app.PowermeterNode = uitreenode(app.DetectorsNode);
            app.PowermeterNode.Text = 'Powermeter';
            app.LightanalysisNode = uitreenode(app.SelectedInstruments);
            app.LightanalysisNode.Text = 'Light analysis';
            app.SpectrometerNode = uitreenode(app.LightanalysisNode);
            app.SpectrometerNode.NodeData = 'ner no';
            app.SpectrometerNode.Text = 'Spectrometer';
            app.DetectionpolarisationNode = uitreenode(app.LightanalysisNode);
            app.DetectionpolarisationNode.Text = 'Detection polarisation';
            app.TWTBPFilterANode = uitreenode(app.LightanalysisNode);
            app.TWTBPFilterANode.Text = 'TWTBPFilter A';
            % Assign Checked Nodes
            app.SelectedInstruments.CheckedNodes = [app.BSsliderNode, app.ShutterNode, app.SteeringmirrorNode, app.CCDiDusNode, app.SpectrometerNode, app.DetectionpolarisationNode];
            % Assign Checked Nodes
            app.SelectedInstruments.CheckedNodesChangedFcn = createCallbackFcn(app, @SelectedInstrumentsCheckedNodesChanged, true);
            app.LEDKnobLabel = uilabel(app.SettingsTab);
            app.LEDKnobLabel.Text = 'LED';
            app.LEDKnob = uiknob(app.SettingsTab, 'continuous');
            app.LEDKnob.Limits = [0 5];
            app.LEDKnob.ValueChangedFcn = createCallbackFcn(app, @LEDKnobValueChanged, true);
            app.Button = uibutton(app.SettingsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '1392886883.svg';
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.Button.Text = '';
            app.KnobLabel = uilabel(app.SettingsTab);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.SettingsTab, 'discrete');
            app.Knob.Items = {'Off', 'On'};
            app.CCDROIPanel = uipanel(app.SettingsTab);
            app.CCDROIPanel.Title = 'CCD ROI';
            app.TabGroup3 = uitabgroup(app.CCDROIPanel);
            app.SingletrackTab = uitab(app.TabGroup3);
            app.SingletrackTab.Title = 'Single track';
            app.centralvalueSpinnerLabel = uilabel(app.SingletrackTab);
            app.centralvalueSpinnerLabel.Text = 'central value';
            app.centralvalueSpinner = uispinner(app.SingletrackTab);
            app.centralvalueSpinner.Limits = [1 256];
            app.centralvalueSpinner.RoundFractionalValues = 'on';
            app.centralvalueSpinner.Tooltip = {'Select a value'};
            app.centralvalueSpinner.Value = 72;
            app.heightSpinnerLabel = uilabel(app.SingletrackTab);
            app.heightSpinnerLabel.Text = 'height';
            app.heightSpinner = uispinner(app.SingletrackTab);
            app.heightSpinner.Limits = [1 128];
            app.heightSpinner.RoundFractionalValues = 'on';
            app.heightSpinner.Value = 50;
            app.SingleTrackImage = uiimage(app.SingletrackTab);
            app.SingleTrackImage.ImageSource = 'singletrack.png';
            app.ImageTab = uitab(app.TabGroup3);
            app.ImageTab.Title = 'Image';
            app.hbinSpinnerLabel = uilabel(app.ImageTab);
            app.hbinSpinnerLabel.Text = 'hbin';
            app.hbinSpinner = uispinner(app.ImageTab);
            app.hbinSpinner.Limits = [1 2000];
            app.hbinSpinner.RoundFractionalValues = 'on';
            app.hbinSpinner.Value = 1;
            app.vbinSpinnerLabel = uilabel(app.ImageTab);
            app.vbinSpinnerLabel.Text = 'vbin';
            app.vbinSpinner = uispinner(app.ImageTab);
            app.vbinSpinner.Limits = [1 256];
            app.vbinSpinner.RoundFractionalValues = 'on';
            app.vbinSpinner.Value = 1;
            app.hstartSpinnerLabel = uilabel(app.ImageTab);
            app.hstartSpinnerLabel.Text = 'hstart';
            app.hstartSpinner = uispinner(app.ImageTab);
            app.hstartSpinner.Limits = [1 1999];
            app.hstartSpinner.RoundFractionalValues = 'on';
            app.hstartSpinner.Value = 1;
            app.hendSpinnerLabel = uilabel(app.ImageTab);
            app.hendSpinnerLabel.Text = 'hend';
            app.hendSpinner = uispinner(app.ImageTab);
            app.hendSpinner.Limits = [2 2000];
            app.hendSpinner.RoundFractionalValues = 'on';
            app.hendSpinner.Value = 2000;
            app.vstartSpinnerLabel = uilabel(app.ImageTab);
            app.vstartSpinnerLabel.Text = 'vstart';
            app.vstartSpinner = uispinner(app.ImageTab);
            app.vstartSpinner.Limits = [1 255];
            app.vstartSpinner.RoundFractionalValues = 'on';
            app.vstartSpinner.Value = 1;
            app.vendSpinnerLabel = uilabel(app.ImageTab);
            app.vendSpinnerLabel.Text = 'vend';
            app.vendSpinner = uispinner(app.ImageTab);
            app.vendSpinner.Limits = [2 256];
            app.vendSpinner.RoundFractionalValues = 'on';
            app.vendSpinner.Value = 256;
            app.ImageImage = uiimage(app.ImageTab);
            app.ImageImage.ImageSource = 'image.png';
            app.SampleinformationPanel = uipanel(app.SettingsTab);
            app.SampleinformationPanel.Title = 'Sample information';
            app.SamplePrepDatePickerLabel = uilabel(app.SampleinformationPanel);
            app.SamplePrepDatePickerLabel.Text = 'SamplePrep';
            app.SamplePrepDatePicker = uidatepicker(app.SampleinformationPanel);
            app.SamplePrepDatePicker.Value = datetime([2024 4 15]);
            app.SampleOfDropDownLabel = uilabel(app.SampleinformationPanel);
            app.SampleOfDropDownLabel.Text = 'SampleOf';
            app.SampleOfDropDown = uidropdown(app.SampleinformationPanel);
            app.SampleOfDropDown.Items = {'CsPbBr3', 'Cs(Sn/Pb)I3'};
            app.SampleOfDropDown.Value = 'Cs(Sn/Pb)I3';
            app.SynthNameEditFieldLabel = uilabel(app.SampleinformationPanel);
            app.SynthNameEditFieldLabel.Text = 'SynthName';
            app.SynthNameEditField = uieditfield(app.SampleinformationPanel, 'text');
            app.SynthNameEditField.Value = 'Sg_April24';
            app.SynthTempEditFieldLabel = uilabel(app.SampleinformationPanel);
            app.SynthTempEditFieldLabel.Text = 'SynthTemp';
            app.SynthTempEditField = uieditfield(app.SampleinformationPanel, 'numeric');
            app.SynthTempEditField.Value = 42;
            app.SynthDilutionEditFieldLabel = uilabel(app.SampleinformationPanel);
            app.SynthDilutionEditFieldLabel.Text = 'SynthDilution';
            app.SynthDilutionEditField = uieditfield(app.SampleinformationPanel, 'numeric');
            app.SynthDilutionEditField.Value = 0.1;
            app.SynthPolymEditFieldLabel = uilabel(app.SampleinformationPanel);
            app.SynthPolymEditFieldLabel.Text = 'SynthPolym';
            app.SynthPolymEditField = uieditfield(app.SampleinformationPanel, 'text');
            app.SynthPolymEditField.Value = 'PS';
            app.SynthPolymPercEditFieldLabel = uilabel(app.SampleinformationPanel);
            app.SynthPolymPercEditFieldLabel.Text = 'SynthPolymPerc';
            app.SynthPolymPercEditField = uieditfield(app.SampleinformationPanel, 'numeric');
            app.SynthPolymPercEditField.Value = 3;
            app.SynthSolvEditFieldLabel = uilabel(app.SampleinformationPanel);
            app.SynthSolvEditFieldLabel.Text = 'SynthSolv';
            app.SynthSolvEditField = uieditfield(app.SampleinformationPanel, 'text');
            app.SynthSolvEditField.Value = 'tol';
            app.IDLocationPanel = uipanel(app.AppGridLayout);
            app.IDLocationPanel.Title = 'ID/Location';
            app.GridLayout = uigridlayout(app.IDLocationPanel);
            app.NCSpinnerLabel = uilabel(app.GridLayout);
            app.NCSpinnerLabel.Text = 'NC';
            app.NCSpinner = uispinner(app.GridLayout);
            app.NCSpinner.ValueChangedFcn = createCallbackFcn(app, @NCSpinnerValueChanged, true);
            app.MarkerUpEditFieldLabel = uilabel(app.GridLayout);
            app.MarkerUpEditFieldLabel.Text = 'MarkerUp';
            app.MarkerUpEditField = uieditfield(app.GridLayout, 'numeric');
            app.MarkerUpEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerUpEditFieldValueChanged, true);
            app.MarkerDownEditFieldLabel = uilabel(app.GridLayout);
            app.MarkerDownEditFieldLabel.Text = 'MarkerDown';
            app.MarkerDownEditField = uieditfield(app.GridLayout, 'numeric');
            app.MarkerDownEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerDownEditFieldValueChanged, true);
            app.Comment = uitextarea(app.AppGridLayout);
            app.Comment.Tooltip = {'Insert comments here'};
            app.Log = uitextarea(app.AppGridLayout);
            app.Log.Tooltip = {'System output'};
            app.MapdurationminPanel = uipanel(app.AppGridLayout);
            app.MapdurationminPanel.Title = 'Map                                                            duration (min)';
            app.map_image = uiaxes(app.MapdurationminPanel);
            title(app.map_image, 'Title')
            app.map_image.Tag = 'SideGraph';
            app.ShowqtyDropDownLabel = uilabel(app.MapdurationminPanel);
            app.ShowqtyDropDownLabel.Text = 'Show qty';
            app.MapShowQuantityDropDown = uidropdown(app.MapdurationminPanel);
            app.MapShowQuantityDropDown.Items = {'Amplitude', 'Intensity', 'Wavelength'};
            app.MapShowQuantityDropDown.Value = 'Intensity';
            app.Go2PointButton = uibutton(app.MapdurationminPanel, 'push');
            app.Go2PointButton.ButtonPushedFcn = createCallbackFcn(app, @Go2PointButtonPushed, true);
            app.Go2PointButton.Text = 'Go2Point';
            app.MapduratminEditField = uieditfield(app.MapdurationminPanel, 'numeric');
            app.MapduratminEditField.Editable = 'off';
            app.Button_2 = uibutton(app.MapdurationminPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'arrows.svg';
            app.Button_2.Text = '';
            app.InstrumentControlsTab = uitabgroup(app.AppGridLayout);
            app.SpectroTab = uitab(app.InstrumentControlsTab);
            app.SpectroTab.Title = 'Spectro';
            app.MonochromatorPanel = uipanel(app.SpectroTab);
            app.MonochromatorPanel.Title = 'Monochromator';
            app.WavelengthnmEditFieldLabel = uilabel(app.MonochromatorPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.MonochromatorPanel, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.WavelengthnmEditField.Value = 519;
            app.InputportDropDownLabel = uilabel(app.MonochromatorPanel);
            app.InputportDropDownLabel.Text = 'Input port';
            app.InputportDropDown = uidropdown(app.MonochromatorPanel);
            app.InputportDropDown.Items = {'Direct', 'Side'};
            app.InputportDropDown.ValueChangedFcn = createCallbackFcn(app, @InputportDropDownValueChanged, true);
            app.InputportDropDown.Value = 'Direct';
            app.OutputportDropDownLabel = uilabel(app.MonochromatorPanel);
            app.OutputportDropDownLabel.Text = 'Output port';
            app.OutputportDropDown = uidropdown(app.MonochromatorPanel);
            app.OutputportDropDown.Items = {'CCD', 'Free space'};
            app.OutputportDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputportDropDownValueChanged, true);
            app.OutputportDropDown.Value = 'Free space';
            app.GratingDropDownLabel = uilabel(app.MonochromatorPanel);
            app.GratingDropDownLabel.Text = 'Grating';
            app.GratingDropDown = uidropdown(app.MonochromatorPanel);
            app.GratingDropDown.Items = {'150', '600', '1200'};
            app.GratingDropDown.ValueChangedFcn = createCallbackFcn(app, @GratingDropDownValueChanged, true);
            app.GratingDropDown.Value = '1200';
            app.CCDPanel = uipanel(app.SpectroTab);
            app.CCDPanel.Title = 'CCD';
            app.ReadoutmodeLabel = uilabel(app.CCDPanel);
            app.ReadoutmodeLabel.Text = 'Acquisition mode';
            app.AndorAcqModeDropDown = uidropdown(app.CCDPanel);
            app.AndorAcqModeDropDown.Items = {'Single scan', 'Accumulate', 'Kinetic series', 'Continuous'};
            app.AndorAcqModeDropDown.Value = 'Single scan';
            app.CCDTLampLabel = uilabel(app.CCDPanel);
            app.CCDTLampLabel.Text = 'CCD T°';
            app.CCDTLamp = uilamp(app.CCDPanel);
            app.GainDropDownLabel = uilabel(app.CCDPanel);
            app.GainDropDownLabel.Enable = 'off';
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.CCDPanel);
            app.GainDropDown.Items = {'Low', 'High'};
            app.GainDropDown.ValueChangedFcn = createCallbackFcn(app, @GainDropDownValueChanged, true);
            app.GainDropDown.Enable = 'off';
            app.GainDropDown.Value = 'Low';
            app.ReadoutmodeLabel_2 = uilabel(app.CCDPanel);
            app.ReadoutmodeLabel_2.Text = 'Readout mode';
            app.AndorReadoutModeDropDown = uidropdown(app.CCDPanel);
            app.AndorReadoutModeDropDown.Items = {'Full Vertical Binning', 'Multi-track', 'Random multi-track', 'Single-track', 'Image'};
            app.AndorReadoutModeDropDown.Value = 'Single-track';
            app.AccumSpinnerLabel = uilabel(app.CCDPanel);
            app.AccumSpinnerLabel.Text = 'Accum';
            app.AccumSpinner = uispinner(app.CCDPanel);
            app.CyctimeSpinnerLabel = uilabel(app.CCDPanel);
            app.CyctimeSpinnerLabel.Text = 'Cyc. time';
            app.CyctimeSpinner = uispinner(app.CCDPanel);
            app.AcquireButton = uibutton(app.CCDPanel, 'push');
            app.AcquireButton.Enable = 'off';
            app.AcquireButton.Text = 'Acquire';
            app.FSMTab = uitab(app.InstrumentControlsTab);
            app.FSMTab.Title = 'FSM';
            app.FSM_xREditField = uieditfield(app.FSMTab, 'numeric');
            app.FSM_xREditField.Editable = 'off';
            app.FSM_xREditField.Value = 42;
            app.FSM_yREditField = uieditfield(app.FSMTab, 'numeric');
            app.FSM_yREditField.Editable = 'off';
            app.FSM_yREditField.Value = 42;
            app.FSM_xTEditField = uieditfield(app.FSMTab, 'numeric');
            app.FSM_xTEditField.ValueChangedFcn = createCallbackFcn(app, @FSM_xTEditFieldValueChanged, true);
            app.FSM_xTEditField.Value = 42;
            app.FSM_yTEditField = uieditfield(app.FSMTab, 'numeric');
            app.FSM_yTEditField.ValueChangedFcn = createCallbackFcn(app, @FSM_yTEditFieldValueChanged, true);
            app.FSM_yTEditField.Value = 42;
            app.ReadoutLabel = uilabel(app.FSMTab);
            app.ReadoutLabel.Text = 'Readout';
            app.YLabel = uilabel(app.FSMTab);
            app.YLabel.Text = 'Y';
            app.XLabel = uilabel(app.FSMTab);
            app.XLabel.Text = 'X';
            app.TargetLabel = uilabel(app.FSMTab);
            app.TargetLabel.Text = 'Target';
            app.UButton = uibutton(app.FSMTab, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.DButton = uibutton(app.FSMTab, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.DButton.Text = 'D';
            app.RButton = uibutton(app.FSMTab, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.RButton.Text = 'R';
            app.LButton = uibutton(app.FSMTab, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.LButton.Text = 'L';
            app.HButton = uibutton(app.FSMTab, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.HButton.Text = 'H';
            app.FSMStepEditFieldLabel = uilabel(app.FSMTab);
            app.FSMStepEditFieldLabel.Text = 'FSM Step';
            app.FSMStepEditField = uieditfield(app.FSMTab, 'numeric');
            app.FSMStepEditField.Limits = [0 600];
            app.FSMStepEditField.Value = 2;
            app.RotationTab = uitab(app.InstrumentControlsTab);
            app.RotationTab.Title = 'Rotation';
            app.SelectedinstrLabel = uilabel(app.RotationTab);
            app.SelectedinstrLabel.Text = 'Selected instr.';
            app.PolarSelectedInstrument = uidropdown(app.RotationTab);
            app.PolarSelectedInstrument.Items = {'Excitation', 'Detection', 'Excitation'};
            app.PolarSelectedInstrument.Value = 'Detection';
            app.ExcPolPanel = uipanel(app.RotationTab);
            app.ExcPolPanel.Title = 'ExcPol';
            app.PolarExcJogFw = uibutton(app.ExcPolPanel, 'push');
            app.PolarExcJogFw.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.PolarExcJogFw.Tag = 'PolarExcFWD';
            app.PolarExcJogFw.Text = 'Fwd';
            app.PolarExcJogBk = uibutton(app.ExcPolPanel, 'push');
            app.PolarExcJogBk.ButtonPushedFcn = createCallbackFcn(app, @BwdButtonPushed, true);
            app.PolarExcJogBk.Tag = 'PolarExcBWD';
            app.PolarExcJogBk.Text = 'Bwd';
            app.ReadoutEditField_4Label = uilabel(app.ExcPolPanel);
            app.ReadoutEditField_4Label.Text = 'Readout';
            app.PolarExcReadoutEditField = uieditfield(app.ExcPolPanel, 'numeric');
            app.PolarExcReadoutEditField.ValueDisplayFormat = '%03.2f';
            app.PolarExcReadoutEditField.Editable = 'off';
            app.PolarExcReadoutEditField.Value = 123.23;
            app.StepEditFieldLabel = uilabel(app.ExcPolPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.PolarExcJogStepEditField = uieditfield(app.ExcPolPanel, 'numeric');
            app.PolarExcJogStepEditField.ValueDisplayFormat = '%03.2f';
            app.PolarExcJogStepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.PolarExcJogStepEditField.Tag = 'PolarExcSTP';
            app.PolarExcJogStepEditField.Value = 123.45;
            app.TargetEditField_4Label = uilabel(app.ExcPolPanel);
            app.TargetEditField_4Label.Text = 'Target';
            app.PolarExcTargetEditField = uieditfield(app.ExcPolPanel, 'numeric');
            app.PolarExcTargetEditField.ValueDisplayFormat = '%03.2f';
            app.PolarExcTargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.PolarExcTargetEditField.Tag = 'PolarExcTrg';
            app.DetPolPanel = uipanel(app.RotationTab);
            app.DetPolPanel.Title = 'DetPol';
            app.PolarDetJogBk = uibutton(app.DetPolPanel, 'push');
            app.PolarDetJogBk.ButtonPushedFcn = createCallbackFcn(app, @BwdButtonPushed, true);
            app.PolarDetJogBk.Tag = 'PolarDetBWD';
            app.PolarDetJogBk.Text = 'Bwd';
            app.PolarDetJogFw = uibutton(app.DetPolPanel, 'push');
            app.PolarDetJogFw.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.PolarDetJogFw.Tag = 'PolarDetFWD';
            app.PolarDetJogFw.Text = 'Fwd';
            app.StepEditFieldLabel_2 = uilabel(app.DetPolPanel);
            app.StepEditFieldLabel_2.Text = 'Step';
            app.PolarDetJogStepEditField = uieditfield(app.DetPolPanel, 'numeric');
            app.PolarDetJogStepEditField.ValueDisplayFormat = '%03.2f';
            app.PolarDetJogStepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.PolarDetJogStepEditField.Tag = 'PolarDetSTP';
            app.PolarDetJogStepEditField.Value = 123.45;
            app.TargetEditField_4Label_2 = uilabel(app.DetPolPanel);
            app.TargetEditField_4Label_2.Text = 'Target';
            app.PolarDetTargetEditField = uieditfield(app.DetPolPanel, 'numeric');
            app.PolarDetTargetEditField.ValueDisplayFormat = '%03.2f';
            app.PolarDetTargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.PolarDetTargetEditField.Tag = 'PolarDetTrg';
            app.ReadoutEditField_4Label_2 = uilabel(app.DetPolPanel);
            app.ReadoutEditField_4Label_2.Text = 'Readout';
            app.PolarDetReadoutEditField = uieditfield(app.DetPolPanel, 'numeric');
            app.PolarDetReadoutEditField.ValueDisplayFormat = '%03.2f';
            app.PolarDetReadoutEditField.Editable = 'off';
            app.PolarDetReadoutEditField.Value = 123.23;
            app.FiltersTab = uitab(app.InstrumentControlsTab);
            app.FiltersTab.Title = 'Filters';
            app.FiltersetAPanel = uipanel(app.FiltersTab);
            app.FiltersetAPanel.Title = 'Filter set A';
            app.BwdButton_3 = uibutton(app.FiltersetAPanel, 'push');
            app.BwdButton_3.ButtonPushedFcn = createCallbackFcn(app, @BwdButtonPushed, true);
            app.BwdButton_3.Tag = 'BWD3';
            app.BwdButton_3.Text = 'Bwd';
            app.BwdButton_2 = uibutton(app.FiltersetAPanel, 'push');
            app.BwdButton_2.ButtonPushedFcn = createCallbackFcn(app, @BwdButtonPushed, true);
            app.BwdButton_2.Tag = 'BWD2';
            app.BwdButton_2.Text = 'Bwd';
            app.FwdButton_2 = uibutton(app.FiltersetAPanel, 'push');
            app.FwdButton_2.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.FwdButton_2.Tag = 'FWD2';
            app.FwdButton_2.Text = 'Fwd';
            app.FwdButton_3 = uibutton(app.FiltersetAPanel, 'push');
            app.FwdButton_3.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.FwdButton_3.Tag = 'FWD3';
            app.FwdButton_3.Text = 'Fwd';
            app.FwdButton = uibutton(app.FiltersetAPanel, 'push');
            app.FwdButton.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.FwdButton.Tag = 'FWD1';
            app.FwdButton.Text = 'Fwd';
            app.BwdButton = uibutton(app.FiltersetAPanel, 'push');
            app.BwdButton.ButtonPushedFcn = createCallbackFcn(app, @BwdButtonPushed, true);
            app.BwdButton.Tag = 'BWD1';
            app.BwdButton.Text = 'Bwd';
            app.AngleTargetLabel = uilabel(app.FiltersetAPanel);
            app.AngleTargetLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.FiltersetAPanel, 'numeric');
            app.TargetEditField.ValueDisplayFormat = '%03.2f';
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.TargetEditField.Tag = 'Trg1';
            app.ReadoutLabel_2 = uilabel(app.FiltersetAPanel);
            app.ReadoutLabel_2.Text = 'Readout';
            app.ReadoutEditField = uieditfield(app.FiltersetAPanel, 'numeric');
            app.ReadoutEditField.ValueDisplayFormat = '%03.2f';
            app.ReadoutEditField.Editable = 'off';
            app.ReadoutEditField.Value = 30;
            app.StepLabel_2 = uilabel(app.FiltersetAPanel);
            app.StepLabel_2.Text = 'Step';
            app.StepEditField = uieditfield(app.FiltersetAPanel, 'numeric');
            app.StepEditField.ValueDisplayFormat = '%3.2f';
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Tag = 'STP1';
            app.TargetLabel_3 = uilabel(app.FiltersetAPanel);
            app.TargetLabel_3.Text = 'Target';
            app.TargetEditField_2 = uieditfield(app.FiltersetAPanel, 'numeric');
            app.TargetEditField_2.ValueDisplayFormat = '%03.2f';
            app.TargetEditField_2.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.TargetEditField_2.Tag = 'Trg2';
            app.TargetEditField_3 = uieditfield(app.FiltersetAPanel, 'numeric');
            app.TargetEditField_3.ValueDisplayFormat = '%03.2f';
            app.TargetEditField_3.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.TargetEditField_3.Tag = 'Trg3';
            app.TargetLabel_2 = uilabel(app.FiltersetAPanel);
            app.TargetLabel_2.Text = 'Target';
            app.ReadoutLabel_3 = uilabel(app.FiltersetAPanel);
            app.ReadoutLabel_3.Text = 'Readout';
            app.ReadoutEditField_2 = uieditfield(app.FiltersetAPanel, 'numeric');
            app.ReadoutEditField_2.ValueDisplayFormat = '%03.2f';
            app.ReadoutEditField_2.Editable = 'off';
            app.StepLabel_3 = uilabel(app.FiltersetAPanel);
            app.StepLabel_3.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.FiltersetAPanel, 'numeric');
            app.StepEditField_2.ValueDisplayFormat = '%03.2f';
            app.StepEditField_2.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField_2.Tag = 'STP2';
            app.ReadoutLabel_4 = uilabel(app.FiltersetAPanel);
            app.ReadoutLabel_4.Text = 'Readout';
            app.ReadoutEditField_3 = uieditfield(app.FiltersetAPanel, 'numeric');
            app.ReadoutEditField_3.ValueDisplayFormat = '%03.2f';
            app.ReadoutEditField_3.Editable = 'off';
            app.ReadoutEditField_3.Value = 234.698;
            app.StepLabel = uilabel(app.FiltersetAPanel);
            app.StepLabel.Text = 'Step';
            app.StepEditField_3 = uieditfield(app.FiltersetAPanel, 'numeric');
            app.StepEditField_3.ValueDisplayFormat = '%03.2f';
            app.StepEditField_3.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField_3.Tag = 'STP3';
            app.FiltersetBPanel = uipanel(app.FiltersTab);
            app.FiltersetBPanel.Title = 'Filter set B';
            app.CustfilterTab = uitab(app.InstrumentControlsTab);
            app.CustfilterTab.Title = 'Cust. filter';
            app.UIAxes2 = uiaxes(app.CustfilterTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.lambdathetalambda0sqrt1leftfracsin2thetan_eff2rightLabel = uilabel(app.CustfilterTab);
            app.lambdathetalambda0sqrt1leftfracsin2thetan_eff2rightLabel.Interpreter = 'latex';
            app.lambdathetalambda0sqrt1leftfracsin2thetan_eff2rightLabel.Text = '$\lambda(\theta) = \lambda(0) \sqrt{1 - \left(\frac{\sin^2(\theta)}{n_{eff}^2}\right)}$';
            app.lambda0Label = uilabel(app.CustfilterTab);
            app.lambda0Label.Interpreter = 'latex';
            app.lambda0Label.Text = '$\lambda(0) = $';
            app.lambda0EditField = uieditfield(app.CustfilterTab, 'numeric');
            app.lambda0EditField.Limits = [380 1000];
            app.lambda0EditField.Value = 561;
            app.n_texteffLabel = uilabel(app.CustfilterTab);
            app.n_texteffLabel.Interpreter = 'latex';
            app.n_texteffLabel.Text = '$n_{\text{eff}} = $';
            app.n_texteffEditField = uieditfield(app.CustfilterTab, 'numeric');
            app.n_texteffEditField.ValueDisplayFormat = '%.2f';
            app.n_texteffEditField.Value = 1.83;
            app.FiltersetDropDownLabel = uilabel(app.CustfilterTab);
            app.FiltersetDropDownLabel.Text = 'Filter set';
            app.FiltersetDropDown = uidropdown(app.CustfilterTab);
            app.FiltersetDropDown.Items = {'A', 'B'};
            app.FiltersetDropDown.Value = 'A';
            app.TranslationTab = uitab(app.InstrumentControlsTab);
            app.TranslationTab.Title = 'Translation';
            app.BSslider2Switch_2Label = uilabel(app.TranslationTab);
            app.BSslider2Switch_2Label.Text = 'BS slider 2';
            app.BSslider2Switch = uiswitch(app.TranslationTab, 'slider');
            app.BSslider3SwitchLabel = uilabel(app.TranslationTab);
            app.BSslider3SwitchLabel.Text = 'BS slider 3';
            app.BSslider3Switch = uiswitch(app.TranslationTab, 'slider');
            app.BSslider1Switch_2Label = uilabel(app.TranslationTab);
            app.BSslider1Switch_2Label.Text = 'BS slider 1';
            app.BSslider1Switch = uiswitch(app.TranslationTab, 'slider');
            app.ShutterSwitchLabel = uilabel(app.TranslationTab);
            app.ShutterSwitchLabel.Text = 'Shutter';
            app.ShutterSwitch = uiswitch(app.TranslationTab, 'slider');
            app.APDsTab = uitab(app.InstrumentControlsTab);
            app.APDsTab.Title = 'APDs';
            app.MeasurementControlsTab = uitabgroup(app.AppGridLayout);
            app.MeasurementControlsTab.SelectionChangedFcn = createCallbackFcn(app, @MeasurementControlsTabSelectionChanged, true);
            app.DMapTab = uitab(app.MeasurementControlsTab);
            app.DMapTab.Title = '2D Map';
            app.x_0SpinnerLabel = uilabel(app.DMapTab);
            app.x_0SpinnerLabel.Text = 'x_0';
            app.x_0Spinner = uispinner(app.DMapTab);
            app.y_0SpinnerLabel = uilabel(app.DMapTab);
            app.y_0SpinnerLabel.Text = 'y_0';
            app.y_0Spinner = uispinner(app.DMapTab);
            app.y_0Spinner.Value = -6.66;
            app.xsizeSpinnerLabel = uilabel(app.DMapTab);
            app.xsizeSpinnerLabel.Text = 'x size';
            app.xsizeSpinner = uispinner(app.DMapTab);
            app.xsizeSpinner.Value = 6;
            app.ysizeSpinnerLabel = uilabel(app.DMapTab);
            app.ysizeSpinnerLabel.Text = 'y size';
            app.ysizeSpinner = uispinner(app.DMapTab);
            app.ysizeSpinner.Value = 6;
            app.StartButton = uibutton(app.DMapTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.stepSpinnerLabel = uilabel(app.DMapTab);
            app.stepSpinnerLabel.Text = 'step';
            app.stepSpinner = uispinner(app.DMapTab);
            app.stepSpinner.Value = 1;
            app.InfoButton = uibutton(app.DMapTab, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Tooltip = {'Show map limits with FSM'};
            app.InfoButton.Text = 'Info';
            app.IfxTab = uitab(app.MeasurementControlsTab);
            app.IfxTab.Title = 'I = f(x)';
            app.instrDropDownLabel = uilabel(app.IfxTab);
            app.instrDropDownLabel.Text = 'instr';
            app.instrDropDown = uidropdown(app.IfxTab);
            app.instrDropDown.Items = {};
            app.instrDropDown.ValueChangedFcn = createCallbackFcn(app, @instrDropDownValueChanged, true);
            app.instrDropDown.Value = {};
            app.startEditFieldLabel = uilabel(app.IfxTab);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.IfxTab, 'numeric');
            app.endEditFieldLabel = uilabel(app.IfxTab);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.IfxTab, 'numeric');
            app.endEditField.Value = 1;
            app.stepEditFieldLabel = uilabel(app.IfxTab);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.IfxTab, 'numeric');
            app.stepEditField.Value = 0.1;
            app.MasterRunButton = uibutton(app.IfxTab, 'push');
            app.MasterRunButton.ButtonPushedFcn = createCallbackFcn(app, @MasterRunButtonPushed, true);
            app.MasterRunButton.Text = 'Run';
            app.inEditFieldLabel = uilabel(app.IfxTab);
            app.inEditFieldLabel.Text = 'in';
            app.instrunitEditField = uieditfield(app.IfxTab, 'text');
            app.instrunitEditField.Value = 's';
            app.MeasurementConfigTab = uitabgroup(app.AppGridLayout);
            app.ParamsTab = uitab(app.MeasurementConfigTab);
            app.ParamsTab.Title = 'Params';
            app.TKEditFieldLabel = uilabel(app.ParamsTab);
            app.TKEditFieldLabel.Text = 'T (K)';
            app.TKEditField = uieditfield(app.ParamsTab, 'numeric');
            app.TKEditField.Value = 300;
            app.LampLabel = uilabel(app.ParamsTab);
            app.LampLabel.Text = '';
            app.StatusLamp = uilamp(app.ParamsTab);
            app.PWnexttoBSCheckBox = uicheckbox(app.ParamsTab);
            app.PWnexttoBSCheckBox.Text = 'PW next to BS';
            app.ExcitationTab = uitab(app.MeasurementConfigTab);
            app.ExcitationTab.Title = 'Excitation';
            app.SourceDropDownLabel = uilabel(app.ExcitationTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.ExcitationTab);
            app.SourceDropDown.Items = {'CW', 'P', 'SK'};
            app.SourceDropDown.Value = 'CW';
            app.EWLnmEditFieldLabel = uilabel(app.ExcitationTab);
            app.EWLnmEditFieldLabel.Text = 'EWL (nm)';
            app.EWLnmEditField = uieditfield(app.ExcitationTab, 'numeric');
            app.EWLnmEditField.Value = 448;
            app.GetPower = uibutton(app.ExcitationTab, 'push');
            app.GetPower.ButtonPushedFcn = createCallbackFcn(app, @GetPowerPushed, true);
            app.GetPower.Icon = 'arrows.svg';
            app.GetPower.Text = '';
            app.PowerEditFieldLabel = uilabel(app.ExcitationTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.ExcPow = uieditfield(app.ExcitationTab, 'numeric');
            app.ExcPow.ValueDisplayFormat = '%3.2e';
            app.ExcPow.ValueChangedFcn = createCallbackFcn(app, @ExcPowValueChanged, true);
            app.ExcPow.Editable = 'off';
            app.Switch = uiswitch(app.ExcitationTab, 'slider');
            app.Switch.Items = {'Sample', 'BS'};
            app.Switch.Value = 'Sample';
            app.DetectionTab = uitab(app.MeasurementConfigTab);
            app.DetectionTab.Title = 'Detection';
            app.DetectorDropDownLabel = uilabel(app.DetectionTab);
            app.DetectorDropDownLabel.Text = 'Detector';
            app.DetectorDropDown = uidropdown(app.DetectionTab);
            app.DetectorDropDown.Items = {'CCD1D', 'CCD2D', 'APDA', 'APDB', 'APDA+B', 'PD1', 'BSI'};
            app.DetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @BkgValueChanged, true);
            app.DetectorDropDown.Value = 'CCD1D';
            app.AcqTimeEditField = uieditfield(app.DetectionTab, 'numeric');
            app.AcqTimeEditField.ValueDisplayFormat = '%.3g';
            app.AcqTimeEditField.ValueChangedFcn = createCallbackFcn(app, @BkgValueChanged, true);
            app.AcqTimeEditField.Value = 1;
            app.tsubacqsubLabel = uilabel(app.DetectionTab);
            app.tsubacqsubLabel.Interpreter = 'html';
            app.tsubacqsubLabel.Text = 't<sub>acq</sub> = ';
            app.sLabel = uilabel(app.DetectionTab);
            app.sLabel.Interpreter = 'html';
            app.sLabel.Text = 's';
            app.backgroundCheckBox = uicheckbox(app.DetectionTab);
            app.backgroundCheckBox.Text = 'background';
            app.freezeCheckBox = uicheckbox(app.DetectionTab);
            app.freezeCheckBox.Text = 'freeze';
            app.addcomButton = uibutton(app.AppGridLayout, 'push');
            app.addcomButton.ButtonPushedFcn = createCallbackFcn(app, @addcomButtonPushed, true);
            app.addcomButton.Tooltip = {'add comment to last measurement'};
            app.addcomButton.Text = 'add com';
            app.CCDTContextMenu = uicontextmenu(app.CARODOTManipUIFigure);
            app.GetCCDTMenu = uimenu(app.CCDTContextMenu);
            app.GetCCDTMenu.MenuSelectedFcn = createCallbackFcn(app, @GetCCDTMenuSelected, true);
            app.GetCCDTMenu.Text = 'Get';
            % Assign app.CCDTContextMenu
            app.CCDTLamp.ContextMenu = app.CCDTContextMenu;
            app.ExportContextMenu = uicontextmenu(app.CARODOTManipUIFigure);
            app.PrinttofigureMenu = uimenu(app.ExportContextMenu);
            app.PrinttofigureMenu.MenuSelectedFcn = createCallbackFcn(app, @PrinttofigureMenuSelected, true);
            app.PrinttofigureMenu.Text = 'Print to figure';
            app.SaveMenu = uimenu(app.ExportContextMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            % Assign app.ExportContextMenu
            app.UIAxes.ContextMenu = app.ExportContextMenu;
            app.map_image.ContextMenu = app.ExportContextMenu;
            % Show the figure after all components are created
            app.CARODOTManipUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CARODOTManip

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CARODOTManipUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.CARODOTManipUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CARODOTManipUIFigure)
        end
    end
end